classdef DAQ_App_for_PHM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        DeviceDropDownLabel  matlab.ui.control.Label
        DeviceDropDown       matlab.ui.control.DropDown
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DAQ %DAQ
        DAQMeasurementTypes = {'Voltage', 'IEPE'};
        DAQSubsystemType = 'AnalogInput';
        DevicesInfo
    end
    
    methods (Access = private)
        
        function deviceinfo = daqListSupportedDevices(app, subsystemTypes, measurementTypes)
            devices = daqlist;
            deviceinfo = devices.DeviceInfo;
            
            deviceinfo = daqFilterDevicesBySubsystem(app, deviceinfo, subsystemTypes, measurementTypes);
            deviceinfo = daqFilterDevicesByMeasurement(app, deviceinfo, measurementTypes);
        end
        
        function filteredDevices = daqFilterDevicesBySubsystem(~, devices, subsystemTypes)
        %daqFilterDevicesBySubsystem Filter DAQ device array by subsystem type
        %  devices is a DAQ device info array
        %  subsystemTypes is a cell array of DAQ subsystem types, for example {'AnalogInput, 'AnalogOutput'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the subsystem types provided
            hasSubsystemArray = false(numel(devices), 1);
            
            % Go through each device and see if it has any of the subsystem types provided
            for ii = 1:numel(devices)
                hasSubsystem = false;
                for jj = 1:numel(subsystemTypes)
                    hasSubsystem = hasSubsystem || ...
                        any(strcmp({devices(ii).Subsystems.SubsystemType}, subsystemTypes{jj}));
                end
                hasSubsystemArray(ii) = hasSubsystem;
            end
            filteredDevices = devices(hasSubsystemArray);
        end
        
        function filteredDevices = daqFilterDevicesByMeasurement(~, devices, measurementTypes)
        %daqFilterDevicesByMeasurement Filter DAQ device array by measurement type
        %  devices is a DAQ device info array
        %  measurementTypes is a cell array of measurement types, for example {'Voltage, 'Current'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the measurement types provided
            hasMeasurementArray = false(numel(devices), 1);
            
            % Go through each device and subsystem and see if it has any of the measurement types provided
            for ii = 1:numel(devices)
                % Get array of available subsystems for the current device
                subsystems = [devices(ii).Subsystems];
                hasMeasurement = false;
                for jj = 1:numel(subsystems)
                    % Get cell array of available measurement types for the current subsystem
                    measurements = subsystems(jj).MeasurementTypesAvailable;
                    for kk = 1:numel(measurementTypes)
                        hasMeasurement = hasMeasurement || ...
                            any(strcmp(measurements, measurementTypes{kk}));
                    end
                end
                hasMeasurementArray(ii) = hasMeasurement;
            end
            filteredDevices = devices(hasMeasurementArray);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setAppViewState(app, 'deviceselection')
            drawnow
            
            devices = daqListSupportedDevices(app, app.DAQSubsystemType, app.DAQMeasurementTypes);
            
            app.DevicesInfo = devices;
            
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            app.DeviceDropDown.Items = ['Select a device'; deviceDescriptions];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DeviceDropDownLabel = uilabel(app.Panel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.Panel);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_App_for_PHM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end