classdef DAQ_GUI_for_PHM_Final_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LiveDataAcquisitionUIFigure     matlab.ui.Figure
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        AcquisitionPanel                matlab.ui.container.Panel
        SendtoServerSwitch              matlab.ui.control.Switch
        SendtoServerSwitchLabel         matlab.ui.control.Label
        LogStatusText                   matlab.ui.control.Label
        LogdatatofileSwitch             matlab.ui.control.Switch
        LogdatatofileSwitchLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Spindle1                        matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        XmaxEditField_2                 matlab.ui.control.NumericEditField
        XmaxEditField_2Label            matlab.ui.control.Label
        XminEditField_2                 matlab.ui.control.NumericEditField
        XminEditField_2Label            matlab.ui.control.Label
        YmaxEditField_3                 matlab.ui.control.NumericEditField
        YmaxEditField_3Label            matlab.ui.control.Label
        YminEditField_3                 matlab.ui.control.NumericEditField
        YminEditField_3Label            matlab.ui.control.Label
        AutoscaleSwitch_2               matlab.ui.control.Switch
        AutoscaleSwitch_2Label          matlab.ui.control.Label
        LiveAxes_5                      matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        AutoscaleSwitch_3               matlab.ui.control.Switch
        AutoscaleSwitch_3Label          matlab.ui.control.Label
        EstimationRemainingUsefulLifeEditField  matlab.ui.control.NumericEditField
        EstimationRemainingUsefulLifeEditFieldLabel  matlab.ui.control.Label
        LiveAxes_6                      matlab.ui.control.UIAxes
        LiveViewPanel_2                 matlab.ui.container.Panel
        NumberofDataEditField           matlab.ui.control.NumericEditField
        NumberofDataEditFieldLabel      matlab.ui.control.Label
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisLabel                      matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        XminEditField                   matlab.ui.control.NumericEditField
        XminEditFieldLabel              matlab.ui.control.Label
        YmaxEditField_2                 matlab.ui.control.NumericEditField
        YmaxEditField_2Label            matlab.ui.control.Label
        YminEditField_2                 matlab.ui.control.NumericEditField
        YminEditField_2Label            matlab.ui.control.Label
        AutoscaleSwitch                 matlab.ui.control.Switch
        AutoscaleSwitchLabel            matlab.ui.control.Label
        LiveAxes_4                      matlab.ui.control.UIAxes
        LiveViewPanel                   matlab.ui.container.Panel
        DNFNumberofZAxisEditField       matlab.ui.control.NumericEditField
        DNFNumberofZAxisEditFieldLabel  matlab.ui.control.Label
        DNFNumberofYAxisEditField       matlab.ui.control.NumericEditField
        DNFNumberofYAxisEditFieldLabel  matlab.ui.control.Label
        DNFNumberofXAxisEditField       matlab.ui.control.NumericEditField
        DNFNumberofXAxisEditFieldLabel  matlab.ui.control.Label
        TimewindowEditField             matlab.ui.control.NumericEditField
        TimewindowsEditFieldLabel       matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        AutoscaleYSwitch                matlab.ui.control.Switch
        AutoscaleYSwitchLabel           matlab.ui.control.Label
        LiveAxes_3                      matlab.ui.control.UIAxes
        LiveAxes_2                      matlab.ui.control.UIAxes
        LiveAxes                        matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        AccuracyRateEditField           matlab.ui.control.NumericEditField
        AccuracyRateLabel               matlab.ui.control.Label
        SelectChangeModelButton         matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        DevicePanel                     matlab.ui.container.Panel
        SesitivityofZAxisEditField      matlab.ui.control.NumericEditField
        SesitivityofZAxisEditFieldLabel  matlab.ui.control.Label
        SesitivityofYAxisEditField      matlab.ui.control.NumericEditField
        SesitivityofYAxisEditFieldLabel  matlab.ui.control.Label
        SesitivityofXAxisEditField      matlab.ui.control.NumericEditField
        SesitivityofXAxisEditFieldLabel  matlab.ui.control.Label
        RateSlider                      matlab.ui.control.Slider
        RatescanssSliderLabel           matlab.ui.control.Label
        RateEdit                        matlab.ui.control.NumericEditField
        ExcitationSourceDropDown        matlab.ui.control.DropDown
        ExcitationSourceDropDownLabel   matlab.ui.control.Label
        CouplingDropDown                matlab.ui.control.DropDown
        CouplingDropDownLabel           matlab.ui.control.Label
        TerminalConfigDropDown          matlab.ui.control.DropDown
        TerminalConfigDropDownLabel     matlab.ui.control.Label
        RangeDropDown                   matlab.ui.control.DropDown
        RangeDropDownLabel              matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        MeasurementTypeDropDownLabel    matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DirectionalNatureLabel          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Spindle2                        matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        AutoscaleSwitch_3_2             matlab.ui.control.Switch
        AutoscaleSwitch_6Label          matlab.ui.control.Label
        EstimationRemainingUsefulLifeEditField_2  matlab.ui.control.NumericEditField
        EstimationRemainingUsefulLifeEditField_2Label  matlab.ui.control.Label
        LiveAxes_6_2                    matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        XmaxEditField_2_2               matlab.ui.control.NumericEditField
        XmaxEditField_4Label            matlab.ui.control.Label
        XminEditField_2_2               matlab.ui.control.NumericEditField
        XminEditField_4Label            matlab.ui.control.Label
        YmaxEditField_3_2               matlab.ui.control.NumericEditField
        YmaxEditField_6Label            matlab.ui.control.Label
        YminEditField_3_2               matlab.ui.control.NumericEditField
        YminEditField_6Label            matlab.ui.control.Label
        AutoscaleSwitch_2_2             matlab.ui.control.Switch
        AutoscaleSwitch_5Label          matlab.ui.control.Label
        LiveAxes_5_2                    matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        AccuracyRateEditField_2         matlab.ui.control.NumericEditField
        AccuracyRateEditField_2Label    matlab.ui.control.Label
        SelectChangeModelButton_2       matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        LiveViewPanel_4                 matlab.ui.container.Panel
        DNFNumberofZAxisEditField_2     matlab.ui.control.NumericEditField
        DNFNumberofZAxisEditField_2Label  matlab.ui.control.Label
        DNFNumberofYAxisEditField_2     matlab.ui.control.NumericEditField
        DNFNumberofYAxisEditField_2Label  matlab.ui.control.Label
        DNFNumberofXAxisEditField_2     matlab.ui.control.NumericEditField
        DNFNumberofXAxisEditField_2Label  matlab.ui.control.Label
        TimewindowEditField_2           matlab.ui.control.NumericEditField
        TimewindowsEditFieldLabel_2     matlab.ui.control.Label
        YmaxEditField__2                matlab.ui.control.NumericEditField
        YmaxEditField_5Label            matlab.ui.control.Label
        YminEditField__2                matlab.ui.control.NumericEditField
        YminEditField_5Label            matlab.ui.control.Label
        AutoscaleYSwitch_2              matlab.ui.control.Switch
        AutoscaleYSwitch_2Label         matlab.ui.control.Label
        LiveAxes_3_2                    matlab.ui.control.UIAxes
        LiveAxes_2_2                    matlab.ui.control.UIAxes
        LiveAxes__2                     matlab.ui.control.UIAxes
        DevicePanel_2                   matlab.ui.container.Panel
        SesitivityofZAxisEditField_2    matlab.ui.control.NumericEditField
        SesitivityofZAxisEditField_2Label  matlab.ui.control.Label
        SesitivityofYAxisEditField_2    matlab.ui.control.NumericEditField
        SesitivityofYAxisEditField_2Label  matlab.ui.control.Label
        SesitivityofXAxisEditField_2    matlab.ui.control.NumericEditField
        SesitivityofXAxisEditField_2Label  matlab.ui.control.Label
        RateSlider_2                    matlab.ui.control.Slider
        RatescanssSliderLabel_2         matlab.ui.control.Label
        RateEdit_2                      matlab.ui.control.NumericEditField
        ExcitationSourceDropDown_2      matlab.ui.control.DropDown
        ExcitationSourceDropDown_2Label  matlab.ui.control.Label
        CouplingDropDown_2              matlab.ui.control.DropDown
        CouplingDropDown_2Label         matlab.ui.control.Label
        TerminalConfigDropDown_2        matlab.ui.control.DropDown
        TerminalConfigDropDown_2Label   matlab.ui.control.Label
        RangeDropDown_2                 matlab.ui.control.DropDown
        RangeDropDown_2Label            matlab.ui.control.Label
        MeasurementTypeDropDown_2       matlab.ui.control.DropDown
        MeasurementTypeDropDown_2Label  matlab.ui.control.Label
        DeviceDropDown_2                matlab.ui.control.DropDown
        DeviceDropDown_2Label           matlab.ui.control.Label
        DirectionalNatureLabel_2        matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        LiveViewPanel_3                 matlab.ui.container.Panel
        NumberofDataEditField_2         matlab.ui.control.NumericEditField
        NumberofDataEditField_2Label    matlab.ui.control.Label
        YAxisDropDown_2                 matlab.ui.control.DropDown
        YAxisDropDown_2Label            matlab.ui.control.Label
        XAxisDropDown_2                 matlab.ui.control.DropDown
        XAxisDropDown_2Label            matlab.ui.control.Label
        XmaxEditField__2                matlab.ui.control.NumericEditField
        XmaxEditField_3Label            matlab.ui.control.Label
        XminEditField__2                matlab.ui.control.NumericEditField
        XminEditField_3Label            matlab.ui.control.Label
        YmaxEditField_2_2               matlab.ui.control.NumericEditField
        YmaxEditField_4Label            matlab.ui.control.Label
        YminEditField_2_2               matlab.ui.control.NumericEditField
        YminEditField_4Label            matlab.ui.control.Label
        AutoscaleSwitch__2              matlab.ui.control.Switch
        AutoscaleSwitch_4Label          matlab.ui.control.Label
        LiveAxes_4_2                    matlab.ui.control.UIAxes
    end

    % 2018/02/09 version 1.0 Andrei Ursache
    % 2019/01/07 version 1.2, AU, Added IEPE measurement type
    % 2020/01/16 version 1.3, AU, updated DAQ code from Session to DataAcquisition interface
    
    % Copyright 2018-2020 The MathWorks, Inc.

    
    properties (Access = private)
        DAQ                   % Handle to DAQ object
        DAQ_2
        DAQMeasurementTypes = {'Accelerometer','Voltage','IEPE','Audio'};  % DAQ input measurement types supported by the app
        DAQSubsystemTypes = {'AnalogInput','AudioInput'};  % DAQ subsystem types supported by the app
        DevicesInfo           % Array of devices that provide analog input voltage or audio input measurements
        DAQMeasurementTypes_2 = {'Accelerometer','Voltage','IEPE','Audio'};  % DAQ input measurement types supported by the app
        DAQSubsystemTypes_2 = {'AnalogInput','AudioInput'};  % DAQ subsystem types supported by the app
        DevicesInfo_2           % Array of devices that provide analog input voltage or audio input measurements
        LogRequested          % Logical value, indicates whether user selected to log data to file from the UI (set by LogdatatofileSwitch)
        TimestampsFIFOBuffer  % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer        % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer2
        DataFIFOBuffer3
        FIFOMaxSize = 1E+6    % Maximum allowed FIFO buffer size for DataFIFOBuffer and TimestampsFIFOBuffer
        LivePlotLine          % Handle to line plot of acquired data
        LivePlotLine_2
        LivePlotLine_3
        LivePlotLine_4
        LivePlotLine_4_2
        LivePlotLine_5
        LivePlotLine_5_2
        LivePlotLine_6
        LivePlotLine_6_2
        LivePlotLine_6_3
        TriggerTime           % Acquisition start time stored as datetime
        TempFilename          % Temporary binary file name, acquired data is logged to this file during acquisition
        TempFile              % Handle of opened binary file, acquired data is logged to this file during acquisition
        Filename = 'daqdata.mat' % Default MAT file name at app start
        Filepath = pwd        % Default folder for saving the MAT file at app start
        dat % Using Data
        dat_2
        CI1_No;% Health Indicator
        CI2_No;
        CI1_We;
        CI2_We;
        mod % ML Model
        No = 1;
        We = 1;
        Len = 2;
        No_2 = 1;
        We_2 = 1;
        Len_2 = 2;
        mdl_RUL;
        expTestData;
        EstRUL;
        CI1;
        CI2;
        timeimg;
        tick = 1;
        t = 1;
        f;
        P1;
        RMS = 0;
        conn;
        status;
        status_2;
        count = 1;
        DataFIFOBuffer_2 = [];
        DataFIFOBuffer2_2 = [];
        DataFIFOBuffer3_2 = [];
        tick_2 = 1;
        CI1_2 = [];
        CI1_No_2 = [];
        CI1_We_2 = [];
        CI2_2 = [];
        CI2_No_2 = [];
        CI2_We_2 = [];
        f_2 = [];
        P1_2 = [];
        t_2 = 1;
        timeimg_2 = [];
        EstRUL_2 = [];
        RMS_2 = 0;
        TimestampsFIFOBuffer_2 = [];
        LivePlotLine__2;
        LivePlotLine_2_2;
        LivePlotLine_3_2;
        LivePlotLine_4__2;
        LivePlotLine_4_2_2;
        LivePlotLine_5__2;
        LivePlotLine_5_2_2;
        LivePlotLine_6__2;
        LivePlotLine_6_2_2;
        LivePlotLine_6_3_2;
    end
    
    methods (Access = private)
        
        
        function scansAvailable_Callback(app, src, ~)
        %scansAvailable_Callback Executes on DAQ object ScansAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.
            
            if ~isvalid(app)
                return
            end
            
            [data,timestamps,triggertime] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');
            if app.LogRequested
                % If Log data to file switch is on
                latestdata = [timestamps, data]';
                fwrite(app.TempFile, latestdata, 'double');
                if timestamps(1)==0
                    app.TriggerTime = triggertime;
                end
            end
            
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(app.DAQ.Rate * app.TimewindowEditField.Value) + 1;
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, buffersize, timestamps);
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, data(:,1));
            app.DataFIFOBuffer2 = storeDataInFIFO(app, app.DataFIFOBuffer2, buffersize, data(:,2));
            app.DataFIFOBuffer3 = storeDataInFIFO(app, app.DataFIFOBuffer3, buffersize, data(:,3));
            
            app.DNFNumberofXAxisEditField.Value = 1/2*(kurtosis(app.DataFIFOBuffer)/3.2504+std(app.DataFIFOBuffer)/0.002); % DNF Number
            app.DNFNumberofYAxisEditField.Value = 1/2*(kurtosis(app.DataFIFOBuffer2)/3.1042+std(app.DataFIFOBuffer2)/0.0035);
            app.DNFNumberofZAxisEditField.Value = 1/2*(kurtosis(app.DataFIFOBuffer3)/3.3807+std(app.DataFIFOBuffer3)/0.0061);
            
            WindowSize = 5; % Window Size [sec]
            buffersize_CI = round(app.DAQ.Rate * WindowSize) + 1;
            app.dat = storeDataInFIFO(app, app.dat, buffersize_CI, data(:,3));
            mn = mean(app.dat);
            sd = std(app.dat);
            rm = rms(app.dat);
            sk = skewness(app.dat);
            ku = kurtosis(app.dat);
            
            [app.f, app.P1] = freqAnalysis(app, app.DataFIFOBuffer3, app.DAQ.Rate);
            
            % Update plot data
            set(app.LivePlotLine, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer);
            set(app.LivePlotLine_2, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer2);
            set(app.LivePlotLine_3, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer3);
            
            if app.DNFNumberofXAxisEditField.Value > app.DNFNumberofYAxisEditField.Value && app.DNFNumberofXAxisEditField.Value > app.DNFNumberofZAxisEditField.Value
                set(app.LivePlotLine, 'Color', 'red');
                set(app.LivePlotLine_2, 'Color', 'blue');
                set(app.LivePlotLine_3, 'Color', 'blue');
                app.Image.ImageSource = 'X.png';
            elseif app.DNFNumberofYAxisEditField.Value > app.DNFNumberofXAxisEditField.Value && app.DNFNumberofYAxisEditField.Value > app.DNFNumberofZAxisEditField.Value
                set(app.LivePlotLine, 'Color', 'blue');
                set(app.LivePlotLine_2, 'Color', 'red');
                set(app.LivePlotLine_3, 'Color', 'blue');
                app.Image.ImageSource = 'Y.png';
            elseif app.DNFNumberofZAxisEditField.Value > app.DNFNumberofXAxisEditField.Value && app.DNFNumberofZAxisEditField.Value > app.DNFNumberofYAxisEditField.Value
                set(app.LivePlotLine, 'Color', 'blue');
                set(app.LivePlotLine_2, 'Color', 'blue');
                set(app.LivePlotLine_3, 'Color', 'red');
                app.Image.ImageSource = 'Z.png';
            else
                set(app.LivePlotLine, 'Color', '#0072BD');
                set(app.LivePlotLine_2, 'Color', '#0072BD');
                set(app.LivePlotLine_3, 'Color', '#0072BD');
            end
            
            app.RMS = storeDataInFIFO(app, app.RMS, 100, rms(app.DataFIFOBuffer3));
            
            set(app.LivePlotLine_5_2, 'Color', 'red');
            set(app.LivePlotLine_5, 'Color', 'blue');
            set(app.LivePlotLine_5, 'XData', app.f, 'YData', app.P1);
            
            app.status = app.mod.model.predictFcn(table(mn, sd, rm, sk, ku, 'VariableNames', {'Mean', 'STD', 'RMS', 'Skewness', 'Kurtosis'}));
            
            if app.status == "No"
                app.Lamp.Color = 'blue';
                app.Label.Text = "Normal";
                app.Label.FontColor = 'b';
                set(app.LivePlotLine_5_2, 'XData', [], 'YData', []);
                if app.XAxisDropDown.Value == "Mean"
                    app.CI1_No = storeDataInFIFO(app, app.CI1_No, fix(app.NumberofDataEditField.Value), mn);
                elseif app.XAxisDropDown.Value == "SD"
                    app.CI1_No = storeDataInFIFO(app, app.CI1_No, fix(app.NumberofDataEditField.Value), sd);
                elseif app.XAxisDropDown.Value == "RMS"
                    app.CI1_No = storeDataInFIFO(app, app.CI1_No, fix(app.NumberofDataEditField.Value), rm);
                elseif app.XAxisDropDown.Value == "Skewness"
                    app.CI1_No = storeDataInFIFO(app, app.CI1_No, fix(app.NumberofDataEditField.Value), sk);
                elseif app.XAxisDropDown.Value == "Kurtosis"
                    app.CI1_No = storeDataInFIFO(app, app.CI1_No, fix(app.NumberofDataEditField.Value), ku);
                end
                if app.YAxisDropDown.Value == "Mean"
                    app.CI2_No = storeDataInFIFO(app, app.CI2_No, fix(app.NumberofDataEditField.Value), mn);
                elseif app.YAxisDropDown.Value == "SD"
                    app.CI2_No = storeDataInFIFO(app, app.CI2_No, fix(app.NumberofDataEditField.Value), sd);
                elseif app.YAxisDropDown.Value == "RMS"
                    app.CI2_No = storeDataInFIFO(app, app.CI2_No, fix(app.NumberofDataEditField.Value), rm);
                elseif app.YAxisDropDown.Value == "Skewness"
                    app.CI2_No = storeDataInFIFO(app, app.CI2_No, fix(app.NumberofDataEditField.Value), sk);
                elseif app.YAxisDropDown.Value == "Kurtosis"
                    app.CI2_No = storeDataInFIFO(app, app.CI2_No, fix(app.NumberofDataEditField.Value), ku);
                end
                app.No = app.No + 1;
                app.Len = app.Len + 1;
                app.AccuracyRateEditField.Value = app.No/app.Len*100;
            elseif app.status == "BD"
                app.Lamp.Color = 'red';
                app.Label.Text = "Ball Defect";
                app.Label.FontColor = 'r';
                if 1.2*mean(app.RMS) < rms(app.DataFIFOBuffer3) || app.tick == 50
                    set(app.LivePlotLine_5_2, 'XData', app.f, 'YData', app.P1);
                end
                
                if app.XAxisDropDown.Value == "Mean"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), mn);
                elseif app.XAxisDropDown.Value == "SD"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), sd);
                elseif app.XAxisDropDown.Value == "RMS"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), rm);
                elseif app.XAxisDropDown.Value == "Skewness"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), sk);
                elseif app.XAxisDropDown.Value == "Kurtosis"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), ku);
                end
                if app.YAxisDropDown.Value == "Mean"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), mn);
                elseif app.YAxisDropDown.Value == "SD"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), sd);
                elseif app.YAxisDropDown.Value == "RMS"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), rm);
                elseif app.YAxisDropDown.Value == "Skewness"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), sk);
                elseif app.YAxisDropDown.Value == "Kurtosis"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), ku);
                end
                app.We = app.We + 1;
                app.Len = app.Len + 1;
                app.AccuracyRateEditField.Value = app.We/app.Len*100;
            elseif app.status == "IRD"
                app.Lamp.Color = 'red';
                app.Label.Text = "Inner Race Defect";
                app.Label.FontColor = 'r';
                if 1.2*mean(app.RMS) < rms(app.DataFIFOBuffer3) || app.tick == 50
                    set(app.LivePlotLine_5_2, 'XData', app.f, 'YData', app.P1);
                end
                
                if app.XAxisDropDown.Value == "Mean"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), mn);
                elseif app.XAxisDropDown.Value == "SD"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), sd);
                elseif app.XAxisDropDown.Value == "RMS"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), rm);
                elseif app.XAxisDropDown.Value == "Skewness"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), sk);
                elseif app.XAxisDropDown.Value == "Kurtosis"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), ku);
                end
                if app.YAxisDropDown.Value == "Mean"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), mn);
                elseif app.YAxisDropDown.Value == "SD"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), sd);
                elseif app.YAxisDropDown.Value == "RMS"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), rm);
                elseif app.YAxisDropDown.Value == "Skewness"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), sk);
                elseif app.YAxisDropDown.Value == "Kurtosis"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), ku);
                end
                app.We = app.We + 1;
                app.Len = app.Len + 1;
                app.AccuracyRateEditField.Value = app.We/app.Len*100;
            elseif app.status == "ORD"
                app.Lamp.Color = 'red';
                app.Label.Text = "Outer Race Defect";
                app.Label.FontColor = 'r';

                if 1.2*mean(app.RMS) < rms(app.DataFIFOBuffer3) || app.tick == 50
                    set(app.LivePlotLine_5_2, 'XData', app.f, 'YData', app.P1);
                end
                
                if app.XAxisDropDown.Value == "Mean"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), mn);
                elseif app.XAxisDropDown.Value == "SD"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), sd);
                elseif app.XAxisDropDown.Value == "RMS"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), rm);
                elseif app.XAxisDropDown.Value == "Skewness"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), sk);
                elseif app.XAxisDropDown.Value == "Kurtosis"
                    app.CI1_We = storeDataInFIFO(app, app.CI1_We, fix(app.NumberofDataEditField.Value), ku);
                end
                if app.YAxisDropDown.Value == "Mean"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), mn);
                elseif app.YAxisDropDown.Value == "SD"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), sd);
                elseif app.YAxisDropDown.Value == "RMS"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), rm);
                elseif app.YAxisDropDown.Value == "Skewness"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), sk);
                elseif app.YAxisDropDown.Value == "Kurtosis"
                    app.CI2_We = storeDataInFIFO(app, app.CI2_We, fix(app.NumberofDataEditField.Value), ku);
                end
                app.We = app.We + 1;
                app.Len = app.Len + 1;
                app.AccuracyRateEditField.Value = app.We/app.Len*100;
            else
                app.Lamp.Color = 'green';
                app.Label.Text = "Error";
                app.Label.FontColor = 'g';
            end
            
            set(app.LivePlotLine_4, 'XData', app.CI1_No, 'YData', app.CI2_No);
            set(app.LivePlotLine_4, 'MarkerEdgeColor', 'blue', 'Marker', 'o');
            set(app.LivePlotLine_4_2, 'XData', app.CI1_We, 'YData', app.CI2_We);
            set(app.LivePlotLine_4_2, 'MarkerEdgeColor', 'red', 'Marker', '*');
            
            if app.t <= 308
                if rem(app.tick, 3) == 1
                    [E,C] = predictRUL(app.mdl_RUL.mdl,[app.expTestData.expTestData.Time(app.t) app.expTestData.expTestData.Condition(app.t)],500);
                    timeimg1 = app.expTestData.expTestData.Time(app.t);
                    app.timeimg = storeDataInFIFO(app, app.timeimg, 308, timeimg1);
                    app.EstRUL = storeDataInFIFO(app, app.EstRUL, 308, hms(E));
                    app.CI1 = storeDataInFIFO(app, app.CI1, 308, hms(C(:,1)));
                    app.CI2 = storeDataInFIFO(app, app.CI2, 308, hms(C(:,2)));
                    app.EstimationRemainingUsefulLifeEditField.Value = hms(E);
                    app.tick = app.tick + 1;
                    app.t = app.t + 1;
                else
                    app.tick = app.tick + 1;
                end
            end

            set(app.LivePlotLine_6, 'XData', app.timeimg, 'YData', app.EstRUL);
            set(app.LivePlotLine_6, 'Color', 'black');
            set(app.LivePlotLine_6_2, 'XData', app.timeimg, 'YData', app.CI1);
            set(app.LivePlotLine_6_2, 'Color', 'blue');
            set(app.LivePlotLine_6_3, 'XData', app.timeimg, 'YData', app.CI2);
            set(app.LivePlotLine_6_3, 'Color', 'blue');
            
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.LiveAxes, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
                xlim(app.LiveAxes_2, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
                xlim(app.LiveAxes_3, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end
            
            %% rem(app.tick, N)일 때, (N/10)초에 한번 서버에 전송
            if rem(app.tick, 10) == 1
%                 try
                send2server(app);
%                 catch
%                     app.LogStatusText.Text = "Server Error";
%                 end
            end
        end
        
        function scansAvailable_Callback_2(app, src, ~)
        %scansAvailable_Callback Executes on DAQ object ScansAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.
            
            if ~isvalid(app)
                return
            end
            
            [data,timestamps,triggertime] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');
%             if app.LogRequested
%                 % If Log data to file switch is on
%                 latestdata = [timestamps, data]';
%                 fwrite(app.TempFile, latestdata, 'double');
%                 if timestamps(1)==0
%                     app.TriggerTime = triggertime;
%                 end
%             end
            
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(app.DAQ_2.Rate * app.TimewindowEditField_2.Value) + 1;
            app.TimestampsFIFOBuffer_2 = storeDataInFIFO(app, app.TimestampsFIFOBuffer_2, buffersize, timestamps);
            app.DataFIFOBuffer_2 = storeDataInFIFO(app, app.DataFIFOBuffer_2, buffersize, data(:,1));
            app.DataFIFOBuffer2_2 = storeDataInFIFO(app, app.DataFIFOBuffer2_2, buffersize, data(:,2));
            app.DataFIFOBuffer3_2 = storeDataInFIFO(app, app.DataFIFOBuffer3_2, buffersize, data(:,3));
            
            app.DNFNumberofXAxisEditField_2.Value = 1/2*(kurtosis(app.DataFIFOBuffer_2)/3.2504+std(app.DataFIFOBuffer_2)/0.002); % DNF Number
            app.DNFNumberofYAxisEditField_2.Value = 1/2*(kurtosis(app.DataFIFOBuffer2_2)/3.1042+std(app.DataFIFOBuffer2_2)/0.0035);
            app.DNFNumberofZAxisEditField_2.Value = 1/2*(kurtosis(app.DataFIFOBuffer3_2)/3.3807+std(app.DataFIFOBuffer3_2)/0.0061);
            
            WindowSize = 5; % Window Size [sec]
            buffersize_CI = round(app.DAQ_2.Rate * WindowSize) + 1;
            app.dat_2 = storeDataInFIFO(app, app.dat_2, buffersize_CI, data(:,3));
            mn = mean(app.dat_2);
            sd = std(app.dat_2);
            rm = rms(app.dat_2);
            sk = skewness(app.dat_2);
            ku = kurtosis(app.dat_2);
            
            [app.f_2, app.P1_2] = freqAnalysis(app, app.DataFIFOBuffer3_2, app.DAQ_2.Rate);
            
            % Update plot data
            set(app.LivePlotLine__2, 'XData', app.TimestampsFIFOBuffer_2, 'YData', app.DataFIFOBuffer_2);
            set(app.LivePlotLine_2_2, 'XData', app.TimestampsFIFOBuffer_2, 'YData', app.DataFIFOBuffer2_2);
            set(app.LivePlotLine_3_2, 'XData', app.TimestampsFIFOBuffer_2, 'YData', app.DataFIFOBuffer3_2);
            
            if app.DNFNumberofXAxisEditField.Value > app.DNFNumberofYAxisEditField.Value && app.DNFNumberofXAxisEditField.Value > app.DNFNumberofZAxisEditField.Value
                set(app.LivePlotLine__2, 'Color', 'red');
                set(app.LivePlotLine_2_2, 'Color', 'blue');
                set(app.LivePlotLine_3_2, 'Color', 'blue');
                app.Image_2.ImageSource = 'X.png';
            elseif app.DNFNumberofYAxisEditField.Value > app.DNFNumberofXAxisEditField.Value && app.DNFNumberofYAxisEditField.Value > app.DNFNumberofZAxisEditField.Value
                set(app.LivePlotLine__2, 'Color', 'blue');
                set(app.LivePlotLine_2_2, 'Color', 'red');
                set(app.LivePlotLine_3_2, 'Color', 'blue');
                app.Image_2.ImageSource = 'Y.png';
            elseif app.DNFNumberofZAxisEditField.Value > app.DNFNumberofXAxisEditField.Value && app.DNFNumberofZAxisEditField.Value > app.DNFNumberofYAxisEditField.Value
                set(app.LivePlotLine__2, 'Color', 'blue');
                set(app.LivePlotLine_2_2, 'Color', 'blue');
                set(app.LivePlotLine_3_2, 'Color', 'red');
                app.Image_2.ImageSource = 'Z.png';
            else
                set(app.LivePlotLine__2, 'Color', '#0072BD');
                set(app.LivePlotLine_2_2, 'Color', '#0072BD');
                set(app.LivePlotLine_3_2, 'Color', '#0072BD');
            end
            
            app.RMS_2 = storeDataInFIFO(app, app.RMS_2, 100, rms(app.DataFIFOBuffer3_2));
            
            set(app.LivePlotLine_5_2_2, 'Color', 'red');
            set(app.LivePlotLine_5__2, 'Color', 'blue');
            set(app.LivePlotLine_5__2, 'XData', app.f, 'YData', app.P1);
            
            app.status_2 = app.mod.model.predictFcn(table(mn, sd, rm, sk, ku, 'VariableNames', {'Mean', 'STD', 'RMS', 'Skewness', 'Kurtosis'}));
            
            if app.status_2 == "No"
                app.Lamp_2.Color = 'blue';
                app.Label_2.Text = "Normal";
                app.Label_2.FontColor = 'b';
                set(app.LivePlotLine_5_2_2, 'XData', [], 'YData', []);
                if app.XAxisDropDown_2.Value == "Mean"
                    app.CI1_No_2 = storeDataInFIFO(app, app.CI1_No_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.XAxisDropDown_2.Value == "SD"
                    app.CI1_No_2 = storeDataInFIFO(app, app.CI1_No_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.XAxisDropDown_2.Value == "RMS"
                    app.CI1_No_2 = storeDataInFIFO(app, app.CI1_No_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.XAxisDropDown_2.Value == "Skewness"
                    app.CI1_No_2 = storeDataInFIFO(app, app.CI1_No_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.XAxisDropDown_2.Value == "Kurtosis"
                    app.CI1_No_2 = storeDataInFIFO(app, app.CI1_No_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                if app.YAxisDropDown_2.Value == "Mean"
                    app.CI2_No_2 = storeDataInFIFO(app, app.CI2_No_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.YAxisDropDown_2.Value == "SD"
                    app.CI2_No_2 = storeDataInFIFO(app, app.CI2_No_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.YAxisDropDown_2.Value == "RMS"
                    app.CI2_No_2 = storeDataInFIFO(app, app.CI2_No_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.YAxisDropDown_2.Value == "Skewness"
                    app.CI2_No_2 = storeDataInFIFO(app, app.CI2_No_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.YAxisDropDown_2.Value == "Kurtosis"
                    app.CI2_No_2 = storeDataInFIFO(app, app.CI2_No_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                app.No_2 = app.No_2 + 1;
                app.Len_2 = app.Len_2 + 1;
                app.AccuracyRateEditField_2.Value = app.No_2/app.Len_2*100;
            elseif app.status_2 == "BD"
                app.Lamp_2.Color = 'red';
                app.Label_2.Text = "Ball Defect";
                app.Label_2.FontColor = 'r';
                if 1.2*mean(app.RMS_2) < rms(app.DataFIFOBuffer3_2) || app.tick_2 == 50
                    set(app.LivePlotLine_5_2_2, 'XData', app.f_2, 'YData', app.P1_2);
                end
                
                if app.XAxisDropDown_2.Value == "Mean"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.XAxisDropDown_2.Value == "SD"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.XAxisDropDown_2.Value == "RMS"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.XAxisDropDown_2.Value == "Skewness"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.XAxisDropDown_2.Value == "Kurtosis"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                if app.YAxisDropDown_2.Value == "Mean"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.YAxisDropDown_2.Value == "SD"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.YAxisDropDown_2.Value == "RMS"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.YAxisDropDown_2.Value == "Skewness"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.YAxisDropDown_2.Value == "Kurtosis"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                app.We_2 = app.We_2 + 1;
                app.Len_2 = app.Len_2 + 1;
                app.AccuracyRateEditField_2.Value = app.We_2/app.Len_2*100;
            elseif app.status_2 == "IRD"
                app.Lamp_2.Color = 'red';
                app.Label_2.Text = "Inner Race Defect";
                app.Label_2.FontColor = 'r';
                if 1.2*mean(app.RMS_2) < rms(app.DataFIFOBuffer3_2) || app.tick == 50
                    set(app.LivePlotLine_5_2_2, 'XData', app.f_2, 'YData', app.P1_2);
                end
                
                if app.XAxisDropDown_2.Value == "Mean"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.XAxisDropDown_2.Value == "SD"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.XAxisDropDown_2.Value == "RMS"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.XAxisDropDown_2.Value == "Skewness"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.XAxisDropDown_2.Value == "Kurtosis"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                if app.YAxisDropDown_2.Value == "Mean"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.YAxisDropDown_2.Value == "SD"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.YAxisDropDown_2.Value == "RMS"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.YAxisDropDown_2.Value == "Skewness"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.YAxisDropDown_2.Value == "Kurtosis"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                app.We_2 = app.We_2 + 1;
                app.Len_2 = app.Len_2 + 1;
                app.AccuracyRateEditField_2.Value = app.We_2/app.Len_2*100;
            elseif app.status_2 == "ORD"
                app.Lamp_2.Color = 'red';
                app.Label_2.Text = "Outer Race Defect";
                app.Label_2.FontColor = 'r';

                if 1.2*mean(app.RMS_2) < rms(app.DataFIFOBuffer3_2) || app.tick_2 == 50
                    set(app.LivePlotLine_5_2_2, 'XData', app.f_2, 'YData', app.P1_2);
                end
                
                if app.XAxisDropDown_2.Value == "Mean"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField.Value), mn);
                elseif app.XAxisDropDown_2.Value == "SD"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.XAxisDropDown_2.Value == "RMS"
                    app.CI1_2_We = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.XAxisDropDown_2.Value == "Skewness"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.XAxisDropDown_2.Value == "Kurtosis"
                    app.CI1_We_2 = storeDataInFIFO(app, app.CI1_We_2, fix(app.NumberofDataEditField_2.Value), ku);
                end%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.YAxisDropDown_2.Value == "Mean"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), mn);
                elseif app.YAxisDropDown_2.Value == "SD"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), sd);
                elseif app.YAxisDropDown_2.Value == "RMS"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), rm);
                elseif app.YAxisDropDown_2.Value == "Skewness"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), sk);
                elseif app.YAxisDropDown_2.Value == "Kurtosis"
                    app.CI2_We_2 = storeDataInFIFO(app, app.CI2_We_2, fix(app.NumberofDataEditField_2.Value), ku);
                end
                app.We_2 = app.We_2 + 1;
                app.Len_2 = app.Len_2 + 1;
                app.AccuracyRateEditField_2.Value = app.We_2/app.Len_2*100;
            else
                app.Lamp_2.Color = 'green';
                app.Label_2.Text = "Error";
                app.Label_2.FontColor = 'g';
            end
            
            set(app.LivePlotLine_4__2, 'XData', app.CI1_No_2, 'YData', app.CI2_No_2);
            set(app.LivePlotLine_4__2, 'MarkerEdgeColor', 'blue', 'Marker', 'o');
            set(app.LivePlotLine_4_2_2, 'XData', app.CI1_We_2, 'YData', app.CI2_We_2);
            set(app.LivePlotLine_4_2_2, 'MarkerEdgeColor', 'red', 'Marker', '*');
            
            if app.t <= 308
                if rem(app.tick_2, 3) == 1
                    [E,C] = predictRUL(app.mdl_RUL.mdl,[app.expTestData.expTestData.Time(app.t_2) app.expTestData.expTestData.Condition(app.t_2)],500);
                    timeimg1 = app.expTestData.expTestData.Time(app.t_2);
                    app.timeimg_2 = storeDataInFIFO(app, app.timeimg, 308, timeimg1);
                    app.EstRUL_2 = storeDataInFIFO(app, app.EstRUL, 308, hms(E));
                    app.CI1_2 = storeDataInFIFO(app, app.CI1, 308, hms(C(:,1)));
                    app.CI2_2 = storeDataInFIFO(app, app.CI2, 308, hms(C(:,2)));
                    app.EstimationRemainingUsefulLifeEditField_2.Value = hms(E);
                    app.tick_2 = app.tick_2 + 1;
                    app.t_2 = app.t_2 + 1;
                else
                    app.tick_2 = app.tick_2 + 1;
                end
            end

            set(app.LivePlotLine_6__2, 'XData', app.timeimg_2, 'YData', app.EstRUL_2);
            set(app.LivePlotLine_6__2, 'Color', 'black');
            set(app.LivePlotLine_6_2_2, 'XData', app.timeimg_2, 'YData', app.CI1_2);
            set(app.LivePlotLine_6_2_2, 'Color', 'blue');
            set(app.LivePlotLine_6_3_2, 'XData', app.timeimg_2, 'YData', app.CI2_2);
            set(app.LivePlotLine_6_3_2, 'Color', 'blue');
            
            if numel(app.TimestampsFIFOBuffer_2) > 1
                xlim(app.LiveAxes__2, [app.TimestampsFIFOBuffer_2(1), app.TimestampsFIFOBuffer_2(end)])
                xlim(app.LiveAxes_2_2, [app.TimestampsFIFOBuffer_2(1), app.TimestampsFIFOBuffer_2(end)])
                xlim(app.LiveAxes_3_2, [app.TimestampsFIFOBuffer_2(1), app.TimestampsFIFOBuffer_2(end)])
            end
            
            %% rem(app.tick, N)일 때, (N/10)초에 한번 서버에 전송
%             if rem(app.tick, 10) == 1
% %                 try
%                 send2server(app);
% %                 catch
% %                     app.LogStatusText.Text = "Server Error";
% %                 end
%             end
        end
        
        function data = storeDataInFIFO(~, data, buffersize, datablock)
        %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
        %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
        %  a smooth live plot update and without continuously increasing memory use.
        %  The most recently acquired data (datablock) is added to the buffer and if the amount of data in the
        %  buffer exceeds the specified buffer size (buffersize) the oldest data is discarded to cap the size of
        %  the data in the buffer to buffersize.
        %  input data is the existing data buffer (column vector Nx1).
        %  buffersize is the desired buffer size (maximum number of rows in data buffer) and can be changed.
        %  datablock is a new data block to be added to the buffer (column vector Kx1).
        %  output data is the updated data buffer (column vector Mx1).
        
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
        end
        
        function [items, itemsData] = getChannelPropertyOptions(~, subsystem, propertyName)
        %getChannelPropertyOptions Get options available for a DAQ channel property
        %  Returns items and itemsData for displaying options in a dropdown component
        %  subsystem is the DAQ subsystem handle corresponding to the DAQ channel
        %  propertyName is channel property name as a character array, and can be
        %    'TerminalConfig', or 'Coupling', or 'Range'.
        %  items is a cell array of possible property values, for example {'DC', 'AC'}
        %  itemsData is [] (empty) for 'TerminalConfig' and 'Coupling', and is a cell array of
        %     available ranges for 'Range', for example {[-10 10], [-1 1]}
            
            switch propertyName
                case 'TerminalConfig'
                    items = cellstr(string(subsystem.TerminalConfigsAvailable));
                    itemsData = [];
                case 'Coupling'
                    items = cellstr(string(subsystem.CouplingsAvailable));
                    itemsData = [];
                case 'Range'
                    numRanges = numel(subsystem.RangesAvailable);
                    items = strings(numRanges,1);
                    itemsData = cell(numRanges,1);
                    for ii = 1:numRanges
                        range = subsystem.RangesAvailable(ii);
                        items(ii) = sprintf('%.2f to %.2f', range.Min, range.Max);
                        itemsData{ii} = [range.Min range.Max];
                    end
                    items = cellstr(items);                    
                case 'ExcitationSource'
                    items = {'Internal','External','None'};
                    itemsData = [];
            end
        end
        
        
        function setAppViewState(app, state)
        %setAppViewState Sets the app in a new state and enables/disables corresponding components
        %  state can be 'deviceselection', 'configuration', 'acquisition', or 'filesave'
        
            switch state                
                case 'deviceselection'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'on';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.LogdatatofileSwitch.Enable = 'off';
                    app.ExcitationSourceDropDown.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.TimewindowEditField.Enable = 'off';
                    app.SelectChangeModelButton.Enable = 'on';
                    app.SesitivityofXAxisEditField.Enable = 'off';
                    app.SesitivityofYAxisEditField.Enable = 'off';
                    app.SesitivityofZAxisEditField.Enable = 'off';
                case 'configuration'
                    app.RateEdit.Enable = 'on';
                    app.RateSlider.Enable = 'on';
                    app.DeviceDropDown.Enable = 'on';
                    app.MeasurementTypeDropDown.Enable = 'on';
                    app.RangeDropDown.Enable = 'on';
                    app.StartButton.Enable = 'on';
                    app.LogdatatofileSwitch.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.TimewindowEditField.Enable = 'on';
                    app.SelectChangeModelButton.Enable = 'on';
                    app.SesitivityofXAxisEditField.Enable = 'on';
                    app.SesitivityofYAxisEditField.Enable = 'on';
                    app.SesitivityofZAxisEditField.Enable = 'on';

                    switch app.DAQ.Channels(1).MeasurementType
                        case 'Accelerometer'
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                            app.ExcitationSourceDropDown.Enable = 'off';
                            app.SesitivityofXAxisEditField.Enable = 'on';
                            app.SesitivityofYAxisEditField.Enable = 'on';
                            app.SesitivityofZAxisEditField.Enable = 'on';
                        case 'Voltage'
                            % Voltage channels do not have ExcitationSource
                            % property, so disable the corresponding UI controls
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                            app.ExcitationSourceDropDown.Enable = 'off';
                            app.SesitivityofXAxisEditField.Enable = 'off';
                            app.SesitivityofYAxisEditField.Enable = 'off';
                            app.SesitivityofZAxisEditField.Enable = 'off';
                        case 'Audio'
                            % Audio channels do not have TerminalConfig, Coupling, and ExcitationSource
                            % properties, so disable the corresponding UI controls
                            app.TerminalConfigDropDown.Enable = 'off';
                            app.CouplingDropDown.Enable = 'off';
                            app.ExcitationSourceDropDown.Enable = 'off';
                            app.SesitivityofXAxisEditField.Enable = 'off';
                            app.SesitivityofYAxisEditField.Enable = 'off';
                            app.SesitivityofZAxisEditField.Enable = 'off';
                        case 'IEPE'
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                            app.ExcitationSourceDropDown.Enable = 'on';
                            app.SesitivityofXAxisEditField.Enable = 'off';
                            app.SesitivityofYAxisEditField.Enable = 'off';
                            app.SesitivityofZAxisEditField.Enable = 'off';
                    end
                case 'acquisition'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.LogdatatofileSwitch.Enable = 'off';
                    app.ExcitationSourceDropDown.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.TimewindowEditField.Enable = 'on';
                    app.SelectChangeModelButton.Enable = 'off';
                    app.SesitivityofXAxisEditField.Enable = 'off';
                    app.SesitivityofYAxisEditField.Enable = 'off';
                    app.SesitivityofZAxisEditField.Enable = 'off';
                    if app.DAQ.Channels(1).MeasurementType == "Accelerometer"
                        app.DAQ.Channels(1).Sensitivity = app.SesitivityofXAxisEditField.Value;
                        app.DAQ.Channels(2).Sensitivity = app.SesitivityofYAxisEditField.Value;
                        app.DAQ.Channels(3).Sensitivity = app.SesitivityofZAxisEditField.Value;
                    end
                case 'filesave'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.LogdatatofileSwitch.Enable = 'off';
                    app.ExcitationSourceDropDown.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.TimewindowEditField.Enable = 'on';
                    app.SelectChangeModelButton.Enable = 'on';
                    app.SesitivityofXAxisEditField.Enable = 'on';
                    app.SesitivityofYAxisEditField.Enable = 'on';
                    app.SesitivityofZAxisEditField.Enable = 'on';
            end
        end
        
        
        function setAppViewState_2(app, state)
        %setAppViewState Sets the app in a new state and enables/disables corresponding components
        %  state can be 'deviceselection', 'configuration', 'acquisition', or 'filesave'
        
            switch state                
                case 'deviceselection'
                    app.RateEdit_2.Enable = 'off';
                    app.RateSlider_2.Enable = 'off';
                    app.DeviceDropDown_2.Enable = 'on';
                    app.MeasurementTypeDropDown_2.Enable = 'off';
                    app.RangeDropDown_2.Enable = 'off';
                    app.TerminalConfigDropDown_2.Enable = 'off';
                    app.CouplingDropDown_2.Enable = 'off';
                    app.ExcitationSourceDropDown_2.Enable = 'off';
                    app.TimewindowEditField_2.Enable = 'off';
                    app.SelectChangeModelButton_2.Enable = 'on';
                    app.SesitivityofXAxisEditField_2.Enable = 'off';
                    app.SesitivityofYAxisEditField_2.Enable = 'off';
                    app.SesitivityofZAxisEditField_2.Enable = 'off';
                case 'configuration'
                    app.RateEdit_2.Enable = 'on';
                    app.RateSlider_2.Enable = 'on';
                    app.DeviceDropDown_2.Enable = 'on';
                    app.MeasurementTypeDropDown_2.Enable = 'on';
                    app.RangeDropDown_2.Enable = 'on';
                    app.TimewindowEditField_2.Enable = 'on';
                    app.SelectChangeModelButton_2.Enable = 'on';
                    app.SesitivityofXAxisEditField_2.Enable = 'on';
                    app.SesitivityofYAxisEditField_2.Enable = 'on';
                    app.SesitivityofZAxisEditField_2.Enable = 'on';

                    switch app.DAQ_2.Channels(1).MeasurementType
                        case 'Accelerometer'
                            app.TerminalConfigDropDown_2.Enable = 'on';
                            app.CouplingDropDown_2.Enable = 'on';
                            app.ExcitationSourceDropDown_2.Enable = 'off';
                            app.SesitivityofXAxisEditField_2.Enable = 'on';
                            app.SesitivityofYAxisEditField_2.Enable = 'on';
                            app.SesitivityofZAxisEditField_2.Enable = 'on';
                        case 'Voltage'
                            % Voltage channels do not have ExcitationSource
                            % property, so disable the corresponding UI controls
                            app.TerminalConfigDropDown_2.Enable = 'on';
                            app.CouplingDropDown_2.Enable = 'on';
                            app.ExcitationSourceDropDown_2.Enable = 'off';
                            app.SesitivityofXAxisEditField_2.Enable = 'off';
                            app.SesitivityofYAxisEditField_2.Enable = 'off';
                            app.SesitivityofZAxisEditField_2.Enable = 'off';
                        case 'Audio'
                            % Audio channels do not have TerminalConfig, Coupling, and ExcitationSource
                            % properties, so disable the corresponding UI controls
                            app.TerminalConfigDropDown_2.Enable = 'off';
                            app.CouplingDropDown_2.Enable = 'off';
                            app.ExcitationSourceDropDown_2.Enable = 'off';
                            app.SesitivityofXAxisEditField_2.Enable = 'off';
                            app.SesitivityofYAxisEditField_2.Enable = 'off';
                            app.SesitivityofZAxisEditField_2.Enable = 'off';
                        case 'IEPE'
                            app.TerminalConfigDropDown_2.Enable = 'on';
                            app.CouplingDropDown_2.Enable = 'on';
                            app.ExcitationSourceDropDown_2.Enable = 'on';
                            app.SesitivityofXAxisEditField_2.Enable = 'off';
                            app.SesitivityofYAxisEditField_2.Enable = 'off';
                            app.SesitivityofZAxisEditField_2.Enable = 'off'; 
                    end
                case 'acquisition'
                    app.RateEdit_2.Enable = 'off';
                    app.RateSlider_2.Enable = 'off';
                    app.DeviceDropDown_2.Enable = 'off';
                    app.MeasurementTypeDropDown_2.Enable = 'off';
                    app.RangeDropDown_2.Enable = 'off';
                    app.TerminalConfigDropDown_2.Enable = 'off';
                    app.CouplingDropDown_2.Enable = 'off';
                    app.ExcitationSourceDropDown_2.Enable = 'off';
                    app.TimewindowEditField_2.Enable = 'on';
                    updateLogdatatofileSwitchComponents(app)
                    app.SelectChangeModelButton_2.Enable = 'off';
                    app.SesitivityofXAxisEditField_2.Enable = 'off';
                    app.SesitivityofYAxisEditField_2.Enable = 'off';
                    app.SesitivityofZAxisEditField_2.Enable = 'off';
                    if app.DAQ_2.Channels(1).MeasurementType == "Accelerometer"
                        app.DAQ_2.Channels(1).Sensitivity = app.SesitivityofXAxisEditField_2.Value;
                        app.DAQ_2.Channels(2).Sensitivity = app.SesitivityofYAxisEditField_2.Value;
                        app.DAQ_2.Channels(3).Sensitivity = app.SesitivityofZAxisEditField_2.Value;
                    end
                case 'filesave'
                    app.RateEdit_2.Enable = 'off';
                    app.RateSlider_2.Enable = 'off';
                    app.DeviceDropDown_2.Enable = 'off';
                    app.MeasurementTypeDropDown_2.Enable = 'off';
                    app.RangeDropDown_2.Enable = 'off';
                    app.TerminalConfigDropDown_2.Enable = 'off';
                    app.CouplingDropDown_2.Enable = 'off';
                    app.ExcitationSourceDropDown_2.Enable = 'off';
                    app.TimewindowEditField_2.Enable = 'on';
                    updateLogdatatofileSwitchComponents(app)   
                    app.SelectChangeModelButton_2.Enable = 'on';
                    app.SesitivityofXAxisEditField_2.Enable = 'on';
                    app.SesitivityofYAxisEditField_2.Enable = 'on';
                    app.SesitivityofZAxisEditField_2.Enable = 'on';
            end
        end
        
        
        function binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,1:3) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), 1:3) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
        end
        
        function deviceinfo = daqListSupportedDevices(app, subsystemTypes, measurementTypes)
        %daqListSupportedDevices Get connected devices that support the specified subsystem and measurement types      
            
            % Detect all connected devices
            devices = daqlist;
            deviceinfo = devices.DeviceInfo;
            
            % Keep a subset of devices which have the specified subystem and measurement types
            deviceinfo = daqFilterDevicesBySubsystem(app, deviceinfo, subsystemTypes);
            deviceinfo = daqFilterDevicesByMeasurement(app, deviceinfo, measurementTypes);
            
        end
                
        function filteredDevices = daqFilterDevicesBySubsystem(~, devices, subsystemTypes)
        %daqFilterDevicesBySubsystem Filter DAQ device array by subsystem type
        %  devices is a DAQ device info array
        %  subsystemTypes is a cell array of DAQ subsystem types, for example {'AnalogInput, 'AnalogOutput'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the subsystem types provided
            hasSubsystemArray = false(numel(devices), 1);
            
            % Go through each device and see if it has any of the subsystem types provided
            for ii = 1:numel(devices)
                hasSubsystem = false;
                for jj = 1:numel(subsystemTypes)
                    hasSubsystem = hasSubsystem || ...
                        any(strcmp({devices(ii).Subsystems.SubsystemType}, subsystemTypes{jj}));
                end
                hasSubsystemArray(ii) = hasSubsystem;
            end
            filteredDevices = devices(hasSubsystemArray);
        end
        
        function filteredDevices = daqFilterDevicesByMeasurement(~, devices, measurementTypes)
        %daqFilterDevicesByMeasurement Filter DAQ device array by measurement type
        %  devices is a DAQ device info array
        %  measurementTypes is a cell array of measurement types, for example {'Voltage, 'Current'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the measurement types provided
            hasMeasurementArray = false(numel(devices), 1);
            
            % Go through each device and subsystem and see if it has any of the measurement types provided
            for ii = 1:numel(devices)
                % Get array of available subsystems for the current device
                subsystems = [devices(ii).Subsystems];
                hasMeasurement = false;
                for jj = 1:numel(subsystems)
                    % Get cell array of available measurement types for the current subsystem
                    measurements = subsystems(jj).MeasurementTypesAvailable;
                    for kk = 1:numel(measurementTypes)
                        hasMeasurement = hasMeasurement || ...
                            any(strcmp(measurements, measurementTypes{kk}));
                    end
                end
                hasMeasurementArray(ii) = hasMeasurement;
            end
            filteredDevices = devices(hasMeasurementArray);
        end
        
        function updateRateUIComponents(app)
        %updateRateUIComponents Updates UI with current rate and time window limits
            
            % Update UI to show the actual data acquisition rate and limits
            value = app.DAQ.Rate;
            app.RateEdit.Limits = app.DAQ.RateLimit;
            app.RateSlider.Limits = app.DAQ.RateLimit;
            app.RateSlider.MajorTicks = [app.DAQ.RateLimit(1) app.DAQ.RateLimit(2)];
            app.RateSlider.MinorTicks = [];
            app.RateEdit.Value = value;
            app.RateSlider.Value = value;
            
            % Update time window limits
            % Minimum time window shows 2 samples
            % Maximum time window corresponds to the maximum specified FIFO buffer size
            minTimeWindow = 1/value;
            maxTimeWindow = app.FIFOMaxSize / value;
            app.TimewindowEditField.Limits = [minTimeWindow, maxTimeWindow];
            
        end
        
        function updateRateUIComponents_2(app)
        %updateRateUIComponents Updates UI with current rate and time window limits
            
            % Update UI to show the actual data acquisition rate and limits
            value_2 = app.DAQ_2.Rate;
            app.RateEdit_2.Limits = app.DAQ_2.RateLimit;
            app.RateSlider_2.Limits = app.DAQ_2.RateLimit;
            app.RateSlider_2.MajorTicks = [app.DAQ_2.RateLimit(1) app.DAQ_2.RateLimit(2)];
            app.RateSlider_2.MinorTicks = [];
            app.RateEdit_2.Value = value_2;
            app.RateSlider_2.Value = value_2;
            
            % Update time window limits
            % Minimum time window shows 2 samples
            % Maximum time window corresponds to the maximum specified FIFO buffer size
            minTimeWindow_2 = 1/value_2;
            maxTimeWindow_2 = app.FIFOMaxSize / value_2;
            app.TimewindowEditField_2.Limits = [minTimeWindow_2, maxTimeWindow_2];
            
        end
        
        
        function closeApp_Callback(app, ~, event, isAcquiring)
        %closeApp_Callback Clean-up after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is called from CloseRequestFcn
        %  of the app UIFigure.
        %   event is the event data of the UIFigure CloseRequestFcn callback.
        %   isAcquiring is a logical flag (true/false) corresponding to DAQ
        %   running state.            
            
            %   Before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   data acquisition object and close file if logging.
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQ)
                        stop(app.DAQ_2)
                        delete(app.DAQ)
                        delete(app.DAQ_2)
                        if app.LogRequested
                            fclose(app.TempFile);
                        end
                    else
                        % Acquisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
            
        end
        
        function updateAutoscaleYSwitchComponents(app)
        %updateAutoscaleYSwitchComponents Updates UI components related to y-axis autoscale
        
            value = app.AutoscaleYSwitch.Value;
            switch value
                case 'Off'
                    app.YminEditField.Enable = 'on';
                    app.YmaxEditField.Enable = 'on';
                    YmaxminValueChanged(app, []);
                case 'On'
                    app.YminEditField.Enable = 'off';
                    app.YmaxEditField.Enable = 'off';
                    app.LiveAxes.YLimMode = 'auto';
                    app.LiveAxes_2.YLimMode = 'auto';
                    app.LiveAxes_3.YLimMode = 'auto';
            end
        end
        
        function updateAutoscaleYSwitchComponents_2(app)
        %updateAutoscaleYSwitchComponents Updates UI components related to y-axis autoscale
        
            value = app.AutoscaleSwitch.Value;
            switch value
                case 'Off'
                    app.XminEditField.Enable = 'on';
                    app.XmaxEditField.Enable = 'on';
                    app.YminEditField_2.Enable = 'on';
                    app.YmaxEditField_2.Enable = 'on';
                    XmaxminEditFieldValueChanged(app, []);
                    YmaxminEditField_2ValueChanged(app, []);
                case 'On'
                    app.XminEditField.Enable = 'off';
                    app.XmaxEditField.Enable = 'off';
                    app.YminEditField_2.Enable = 'off';
                    app.YmaxEditField_2.Enable = 'off';
                    app.LiveAxes_4.XLimMode = 'auto';
                    app.LiveAxes_4.YLimMode = 'auto';
            end
        end
        
        function updateAutoscaleYSwitchComponents_3(app)
        %updateAutoscaleYSwitchComponents Updates UI components related to y-axis autoscale
        
            value = app.AutoscaleSwitch_2.Value;
            switch value
                case 'Off'
                    app.XminEditField_2.Enable = 'on';
                    app.XmaxEditField_2.Enable = 'on';
                    app.YminEditField_3.Enable = 'on';
                    app.YmaxEditField_3.Enable = 'on';
                    XmaxminEditField_2ValueChanged(app, []);
                    YmaxminEditField_3ValueChanged(app, []);
                case 'On'
                    app.XminEditField_2.Enable = 'off';
                    app.XmaxEditField_2.Enable = 'off';
                    app.YminEditField_3.Enable = 'off';
                    app.YmaxEditField_3.Enable = 'off';
                    app.LiveAxes_5.XLimMode = 'auto';
                    app.LiveAxes_5.YLimMode = 'auto';
            end
        end
        
        function updateAutoscaleYSwitchComponents_4(app)
        %updateAutoscaleYSwitchComponents Updates UI components related to y-axis autoscale
        
            value = app.AutoscaleSwitch_3.Value;
            switch value
                case 'Off'
                    ylim(app.LiveAxes_6, [0, 400]);
                case 'On'
                    app.LiveAxes_6.YLimMode = 'auto';
            end
        end
        
        function [f, P1] = freqAnalysis(app, raw, SamplingRate)
            %NyqFreq = SamplingRate / 2;
            y = fft(raw);
            P2 = abs(y / length(raw));
            P1 = P2(1:fix(length(raw)/2) + 1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = SamplingRate * (0:(fix(length(raw)/2)))/length(raw);
        end 
        
        function updateChannelMeasurementComponents(app)
        %updateChannelMeasurementComponents Updates channel properties and measurement UI components
            measurementType = app.MeasurementTypeDropDown.Value;

            % Get selected DAQ device index (to be used with DaqDevicesInfo list)
            deviceIndex = app.DeviceDropDown.Value - 1;
            deviceID = app.DevicesInfo(deviceIndex).ID;
            vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                        
            % Get DAQ subsystem information (analog input or audio input)
            % Analog input or analog output subsystems are the first subsystem of the device
            subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
             
            % Delete existing data acquisition object
            delete(app.DAQ);
            app.DAQ = [];
            
            % Create a new data acquisition object
            d = daq(vendor);

%            addinput(d, deviceID, app.ChannelDropDown.Value, measurementType);
%             addinput(d, deviceID, "ai0", measurementType);
%             addinput(d, deviceID, "ai1", measurementType);
%             addinput(d, deviceID, "ai2", measurementType);
            addinput(d, deviceID, "ai0", measurementType);
            addinput(d, deviceID, "ai1", measurementType);
            addinput(d, deviceID, "ai2", measurementType);
            
            % Configure DAQ ScansAvailableFcn callback function
            d.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
            
            % Store data acquisition object handle in DAQ app property
            app.DAQ = d;
             
            % Only 'Voltage', 'IEPE' and 'Audio' measurement types are supported in this version of the app
            % Depending on what type of device is selected, populate the UI elements channel properties
            switch subsystem.SubsystemType
                case 'AnalogInput'                                       
                    % Populate dropdown with available channel 'TerminalConfig' options
                    app.TerminalConfigDropDown.Items = getChannelPropertyOptions(app, subsystem, 'TerminalConfig');
                    % Update UI with the actual channel property value
                    % (default value is not necessarily first in the list)
                    % DropDown Value must be set as a character array in MATLAB R2017b
                    app.TerminalConfigDropDown.Value = d.Channels(1).TerminalConfig;
                    app.TerminalConfigDropDown.Tag = 'TerminalConfig';
                    
                    % Populate dropdown with available channel 'Coupling' options
                    app.CouplingDropDown.Items =  getChannelPropertyOptions(app, subsystem, 'Coupling');
                    % Update UI with the actual channel property value
                    app.CouplingDropDown.Value = d.Channels(1).Coupling;
                    app.CouplingDropDown.Tag = 'Coupling';
                                        
                    % Populate dropdown with available channel 'ExcitationSource' options
                    if strcmpi(measurementType, 'IEPE')
                        app.ExcitationSourceDropDown.Items = getChannelPropertyOptions(app, subsystem, 'ExcitationSource');
                        app.ExcitationSourceDropDown.Value = d.Channels(1).ExcitationSource;
                        app.ExcitationSourceDropDown.Tag = 'ExcitationSource';
                    else
                        app.ExcitationSourceDropDown.Items = {''};
                    end
                    
                    ylabel(app.LiveAxes, 'Voltage (V)')
                    ylabel(app.LiveAxes_2, 'Voltage (V)')
                    ylabel(app.LiveAxes_3, 'Voltage (V)')
                                        
                case 'AudioInput'
                    ylabel(app.LiveAxes, 'Normalized amplitude')
                    ylabel(app.LiveAxes_2, 'Normalized amplitude')
                    ylabel(app.LiveAxes_3, 'Normalized amplitude')
            end
            
            % Update UI with current rate and time window limits
            updateRateUIComponents(app)
                    
            % Populate dropdown with available 'Range' options
            [rangeItems, rangeItemsData] = getChannelPropertyOptions(app, subsystem, 'Range');
            app.RangeDropDown.Items = rangeItems;
            app.RangeDropDown.ItemsData = rangeItemsData;
            
            % Update UI with current channel 'Range'
            currentRange = d.Channels(1).Range;
            app.RangeDropDown.Value = [currentRange.Min currentRange.Max];
            app.RangeDropDown.Tag = 'Range';
            
            app.DeviceDropDown.Items{1} = 'Deselect device';
            
            % Enable DAQ device, channel properties, and start acquisition UI components
            setAppViewState(app, 'configuration');
        end


        function updateChannelMeasurementComponents_2(app)
        %updateChannelMeasurementComponents Updates channel properties and measurement UI components
            measurementType_2 = app.MeasurementTypeDropDown_2.Value;

            % Get selected DAQ device index (to be used with DaqDevicesInfo list)
            deviceIndex_2 = app.DeviceDropDown_2.Value - 1;
            deviceID_2 = app.DevicesInfo_2(deviceIndex_2).ID;
            vendor_2 = app.DevicesInfo_2(deviceIndex_2).Vendor.ID;
                        
            % Get DAQ subsystem information (analog input or audio input)
            % Analog input or analog output subsystems are the first subsystem of the device
            subsystem_2 = app.DevicesInfo_2(deviceIndex_2).Subsystems(1);
            
            % Delete existing data acquisition object
            delete(app.DAQ_2);
            app.DAQ_2 = [];
            
            % Create a new data acquisition object
            d_2 = daq(vendor_2);
%            addinput(d, deviceID, app.ChannelDropDown.Value, measurementType);
%             addinput(d, deviceID, "ai0", measurementType);
%             addinput(d, deviceID, "ai1", measurementType);
%             addinput(d, deviceID, "ai2", measurementType);
            
            addinput(d_2, deviceID_2, "ai0", measurementType_2);
            addinput(d_2, deviceID_2, "ai1", measurementType_2);
            addinput(d_2, deviceID_2, "ai2", measurementType_2);
            
            % Configure DAQ ScansAvailableFcn callback function
            d_2.ScansAvailableFcn = @(src,event) scansAvailable_Callback_2(app, src, event);
            
            % Store data acquisition object handle in DAQ app property
            app.DAQ_2 = d_2;
             
            % Only 'Voltage', 'IEPE' and 'Audio' measurement types are supported in this version of the app
            % Depending on what type of device is selected, populate the UI elements channel properties
            switch subsystem_2.SubsystemType
                case 'AnalogInput'                                       
                    % Populate dropdown with available channel 'TerminalConfig' options
                    app.TerminalConfigDropDown_2.Items = getChannelPropertyOptions(app, subsystem_2, 'TerminalConfig');
                    % Update UI with the actual channel property value
                    % (default value is not necessarily first in the list)
                    % DropDown Value must be set as a character array in MATLAB R2017b
                    app.TerminalConfigDropDown_2.Value = d_2.Channels(1).TerminalConfig;
                    app.TerminalConfigDropDown_2.Tag = 'TerminalConfig';
                    
                    % Populate dropdown with available channel 'Coupling' options
                    app.CouplingDropDown_2.Items =  getChannelPropertyOptions(app, subsystem_2, 'Coupling');
                    % Update UI with the actual channel property value
                    app.CouplingDropDown_2.Value = d_2.Channels(1).Coupling;
                    app.CouplingDropDown_2.Tag = 'Coupling';
                                        
                    % Populate dropdown with available channel 'ExcitationSource' options
                    if strcmpi(measurementType_2, 'IEPE')
                        app.ExcitationSourceDropDown_2.Items = getChannelPropertyOptions(app, subsystem_2, 'ExcitationSource');
                        app.ExcitationSourceDropDown_2.Value = d_2.Channels(1).ExcitationSource;
                        app.ExcitationSourceDropDown_2.Tag = 'ExcitationSource';
                    else
                        app.ExcitationSourceDropDown_2.Items = {''};
                    end
                    
                    ylabel(app.LiveAxes__2, 'Voltage (V)')
                    ylabel(app.LiveAxes_2_2, 'Voltage (V)')
                    ylabel(app.LiveAxes_3_2, 'Voltage (V)')
                                        
                case 'AudioInput'
                    ylabel(app.LiveAxes__2, 'Normalized amplitude')
                    ylabel(app.LiveAxes_2_2, 'Normalized amplitude')
                    ylabel(app.LiveAxes_3_2, 'Normalized amplitude')
            end
            
            % Update UI with current rate and time window limits
            updateRateUIComponents_2(app)
                    
            % Populate dropdown with available 'Range' options
            [rangeItems_2, rangeItemsData_2] = getChannelPropertyOptions(app, subsystem_2, 'Range');
            app.RangeDropDown_2.Items = rangeItems_2;
            app.RangeDropDown_2.ItemsData = rangeItemsData_2;
            
            % Update UI with current channel 'Range'
            currentRange_2 = d_2.Channels(1).Range;
            app.RangeDropDown_2.Value = [currentRange_2.Min currentRange_2.Max];
            app.RangeDropDown_2.Tag = 'Range';
            
            app.DeviceDropDown_2.Items{1} = 'Deselect device';
            
            % Enable DAQ device, channel properties, and start acquisition UI components
            setAppViewState_2(app, 'configuration');
        end
        
        function updateLogdatatofileSwitchComponents(app)
            value = app.LogdatatofileSwitch.Value;
            switch value
                case 'Off'
                    app.LogRequested = false;
                case 'On'
                    app.LogRequested = true;
            end
        end
        
        function send2server(app)
            value = app.SendtoServerSwitch.Value;
            tablename = 'Data';
            datasource = 'RULTest';
            username = 'simlab1220';
            password = 'artlab1220';
            if value == "On"
                try           
                    if isempty(app.conn.Message) == 1
                        app.LogStatusText.Text = 'Server Connected';
                    else
                        app.LogStatusText.Text = 'Server Error';
                        try
                            app.conn = database(datasource,username,password);
                        catch
                            app.LogStatusText.Text = 'Server Connecting Failed';
                        end
                    end
                    datet = datetime;
                    EE = app.EstRUL(app.t-1);
                    CC1 = app.CI1(app.t-1);
                    CC2 = app.CI2(app.t-1);
                    stt = app.status;
                    acc = app.AccuracyRateEditField.Value;
                    byte_datetime = whos('datet').bytes;
                    byte_status = whos('stt').bytes;
                    byte_Acc = whos('acc').bytes;
                    byte_EstRUL = whos('EE').bytes;
                    byte_CI1 = whos('CC1').bytes;
                    byte_CI2 = whos('CC2').bytes;
                    ser = "Yes";
                    DataSize = byte_datetime + byte_status + byte_Acc + byte_EstRUL + byte_CI1 + byte_CI2;
                    data = table(datet, app.status, app.AccuracyRateEditField.Value, app.EstRUL(app.t-1), app.CI1(app.t-1), app.CI2(app.t-1), 'VariableNames',{'datetime' 'Condition' 'Accuracy' 'RUL' 'CI1' 'CI2'});
                    sqlwrite(app.conn,tablename,data)
                    log_data = table(app.count, datet, DataSize, ser, 'VariableNames',{'Count', 'datetime' 'DataSize' 'Upload'});
                    writetable(log_data, 'OutputData.txt', 'WriteMode', 'Append');
                    app.count = app.count + 1;
                catch
                    ser = "No";
                    DataSize = byte_datetime + byte_status + byte_Acc + byte_EstRUL + byte_CI1 + byte_CI2;
                    log_data = table(app.count, datet, DataSize, ser, 'VariableNames',{'Count', 'datetime' 'DataSize' 'Upload'});
                    writetable(log_data, 'OutputData.txt', 'WriteMode', 'Append');
                    app.count = app.count + 1;
                    app.LogStatusText.Text = "Send to Server Failed";
                end
            end       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % This function executes when the app starts, before user interacts with UI
            
            % Set the app controls in device selection state
            setAppViewState(app, 'deviceselection');
            setAppViewState_2(app, 'deviceselection');
            drawnow
            
            % Get connected devices that have the supported subsystem and measurement types
            devices = daqListSupportedDevices(app, app.DAQSubsystemTypes, app.DAQMeasurementTypes);
            devices_2 = daqListSupportedDevices(app, app.DAQSubsystemTypes_2, app.DAQMeasurementTypes_2);
            
            % Store DAQ device information (filtered list) into DevicesInfo app property
            % This is used by other functions in the app
            app.DevicesInfo = devices;
            app.DevicesInfo_2 = devices_2;
           
            % Populate the device drop down list with cell array of composite device names (ID + model)
            % First element is "Select a device"
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            deviceDescriptions_2 = cellstr(string({devices_2.ID}') + " [" + string({devices_2.Model}') + "]");
            app.DeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            app.DeviceDropDown_2.Items = ['Select a device'; deviceDescriptions_2];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DeviceDropDown.ItemsData = 1:numel(devices)+1;
            app.DeviceDropDown_2.ItemsData = 1:numel(devices_2)+1;
            
            % Create a line plot and store its handle in LivePlot app property
            % This is used for updating the live plot from scansAvailable_Callback function
            app.LivePlotLine = plot(app.LiveAxes, NaN, NaN);
            app.LivePlotLine_2 = plot(app.LiveAxes_2, NaN, NaN);
            app.LivePlotLine_3 = plot(app.LiveAxes_3, NaN, NaN);
            app.LivePlotLine_4 = scatter(app.LiveAxes_4, NaN, NaN);
            hold(app.LiveAxes_4, 'on')
            app.LivePlotLine_4_2 = scatter(app.LiveAxes_4, NaN, NaN);
            app.LivePlotLine_5 = plot(app.LiveAxes_5, NaN, NaN);
            hold(app.LiveAxes_5, 'on')
            app.LivePlotLine_5_2 = plot(app.LiveAxes_5, NaN, NaN);
            app.LivePlotLine_6 = plot(app.LiveAxes_6, NaN, NaN);
            hold(app.LiveAxes_6, 'on')
            app.LivePlotLine_6_2 = plot(app.LiveAxes_6, NaN, NaN);
            app.LivePlotLine_6_3 = plot(app.LiveAxes_6, NaN, NaN);
            
            app.LivePlotLine__2 = plot(app.LiveAxes__2, NaN, NaN);
            app.LivePlotLine_2_2 = plot(app.LiveAxes_2_2, NaN, NaN);
            app.LivePlotLine_3_2 = plot(app.LiveAxes_3_2, NaN, NaN);
            app.LivePlotLine_4__2 = scatter(app.LiveAxes_4_2, NaN, NaN);
            hold(app.LiveAxes_4_2, 'on')
            app.LivePlotLine_4_2_2 = scatter(app.LiveAxes_4_2, NaN, NaN);
            app.LivePlotLine_5__2 = plot(app.LiveAxes_5_2, NaN, NaN);
            hold(app.LiveAxes_5_2, 'on')
            app.LivePlotLine_5_2_2 = plot(app.LiveAxes_5_2, NaN, NaN);
            app.LivePlotLine_6__2 = plot(app.LiveAxes_6_2, NaN, NaN);
            hold(app.LiveAxes_6_2, 'on')
            app.LivePlotLine_6_2_2 = plot(app.LiveAxes_6_2, NaN, NaN);
            app.LivePlotLine_6_3_2 = plot(app.LiveAxes_6_2, NaN, NaN);
            
            % Turn off axes toolbar and data tips for live plot axes
            app.LiveAxes.Toolbar.Visible = 'off';
            app.LiveAxes_2.Toolbar.Visible = 'off';
            app.LiveAxes_3.Toolbar.Visible = 'off';
            app.LiveAxes_4.Toolbar.Visible = 'off';
            app.LiveAxes_5.Toolbar.Visible = 'off';
            app.LiveAxes_6.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.LiveAxes);
            disableDefaultInteractivity(app.LiveAxes_2);
            disableDefaultInteractivity(app.LiveAxes_3);
            disableDefaultInteractivity(app.LiveAxes_4);
            disableDefaultInteractivity(app.LiveAxes_5);
            disableDefaultInteractivity(app.LiveAxes_6);
            
            app.LiveAxes__2.Toolbar.Visible = 'off';
            app.LiveAxes_2_2.Toolbar.Visible = 'off';
            app.LiveAxes_3_2.Toolbar.Visible = 'off';
            app.LiveAxes_4_2.Toolbar.Visible = 'off';
            app.LiveAxes_5_2.Toolbar.Visible = 'off';
            app.LiveAxes_6_2.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.LiveAxes__2);
            disableDefaultInteractivity(app.LiveAxes_2_2);
            disableDefaultInteractivity(app.LiveAxes_3_2);
            disableDefaultInteractivity(app.LiveAxes_4_2);
            disableDefaultInteractivity(app.LiveAxes_5_2);
            disableDefaultInteractivity(app.LiveAxes_6_2);

            % Initialize the AutoscaleYSwitch, YminSpinner, and YmaxSpinner components in the correct
            % state (AutoscaleYSwitch enabled, YminSpinner and YmaxSpinner disabled).
            updateAutoscaleYSwitchComponents(app)
            updateAutoscaleYSwitchComponents_2(app)
            updateAutoscaleYSwitchComponents_3(app)
            %수정
            
            % ODBC Database confiugration 
            datasource = 'RULTest';
            username = 'simlab1220';
            password = 'artlab1220';
            
            try
                app.conn = database(datasource,username,password); % MatLAB에서 ODBC system DSN 설정을 먼저 해야 함
            catch
                app.LogStatusText.Text = 'Server Error';
            end
                
            if isempty(app.conn.Message) == 1
                app.LogStatusText.Text = 'Server Connected';
            else
                app.LogStatusText.Text = 'Server Error';
            end
            
            app.mod = load('Learned mod.mat');
            app.mdl_RUL = load('RUL.mat');
            app.expTestData = load('expTestData.mat');
            app.Lamp.Color = 'yellow';
            app.Label.Text = "Initialize";
            app.Lamp_2.Color = 'yellow';
            app.Label_2.Text = "Initialize";
            
            app.LiveAxes_6.XLimMode = 'auto';
            app.LiveAxes_6_2.XLimMode = 'auto';
            ylim(app.LiveAxes_6, [0, 400]);
            ylim(app.LiveAxes_6_2, [0, 400]);
            
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            value = app.DeviceDropDown.Value;
            
            if ~isempty(value)
                % Device index is offset by 1 because first element in device dropdown
                % is "Select a device" (not a device).
                deviceIndex = value-1 ;
                
                % Reset channel property options
                app.MeasurementTypeDropDown.Items = {''};
                app.RangeDropDown.Items = {''};
                app.TerminalConfigDropDown.Items = {''};
                app.CouplingDropDown.Items = {''};
                app.ExcitationSourceDropDown.Items = {''};
                setAppViewState(app, 'deviceselection');

                
                % Delete data acquisition object, as a new one will be created for the newly selected device
                delete(app.DAQ);
                app.DAQ = [];
                
                if deviceIndex > 0
                    % If a device is selected
                    
                    % Get subsystem information to update channel dropdown list and channel property options
                    % For devices that have an analog input or an audio input subsystem, this is the first subsystem
                    subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
                                        
                    % Populate available measurement types for the selected device
                    app.MeasurementTypeDropDown.Items = intersect(app.DAQMeasurementTypes,...
                                subsystem.MeasurementTypesAvailable, 'stable');

                    % Update channel and channel property options
                    updateChannelMeasurementComponents(app)

                else
                    % If no device is selected

                    % Delete existing data acquisition object
                    delete(app.DAQ);
                    app.DAQ = [];
                    
                    app.DeviceDropDown.Items{1} = 'Select a device';
                    
                    setAppViewState(app, 'deviceselection');
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                               
            % Disable DAQ device, channel properties, and start acquisition UI components
            setAppViewState(app, 'acquisition'); 
            setAppViewState_2(app, 'acquisition'); 
            
            if app.LogRequested
                % If Log data to file switch is on
                % Create and open temporary binary file to log data to disk
                app.TempFilename = tempname;
                app.TempFile = fopen(app.TempFilename, 'w');
            end
            
            % Reset FIFO buffer data
            app.DataFIFOBuffer = [];
            app.DataFIFOBuffer2 = [];
            app.DataFIFOBuffer3 = [];
            app.tick = 1;
            app.CI1 = [];
            app.CI1_No = [];
            app.CI1_We = [];
            app.CI2 = [];
            app.CI2_No = [];
            app.CI2_We = [];
            app.f = [];
            app.P1 = [];
            app.t = 1;
            app.timeimg = [];
            app.EstRUL = [];
            app.RMS = 0;
            
            app.TimestampsFIFOBuffer = [];
            
            app.DataFIFOBuffer_2 = [];
            app.DataFIFOBuffer2_2 = [];
            app.DataFIFOBuffer3_2 = [];
            app.tick_2 = 1;
            app.CI1_2 = [];
            app.CI1_No_2 = [];
            app.CI1_We_2 = [];
            app.CI2_2 = [];
            app.CI2_No_2 = [];
            app.CI2_We_2 = [];
            app.f_2 = [];
            app.P1_2 = [];
            app.t_2 = 1;
            app.timeimg_2 = [];
            app.EstRUL_2 = [];
            app.RMS_2 = 0;
            
            app.TimestampsFIFOBuffer_2 = [];
            
            try
                start(app.DAQ,'continuous');
                start(app.DAQ_2,'continuous');
            catch exception
                % In case of error show it and revert the change
                uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Start error');   
                setAppViewState(app, 'configuration'); 
            end
            
            % Clear Log status text
            app.LogStatusText.Text = '';
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            setAppViewState(app, 'filesave');
            setAppViewState_2(app, 'filesave');
            stop(app.DAQ);
            stop(app.DAQ_2);
            close(app.conn)
            if app.LogRequested
                % Log data to file switch is on
                % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)
                
                % Close temporary binary file
                fclose(app.TempFile);
                
                
                % Gather metadata in preparation for saving to MAT file
                % Store relevant Daq device info
                deviceInfo = get(app.DevicesInfo(app.DeviceDropDown.Value-1));
                deviceInfo.Vendor = get(deviceInfo.Vendor);
                deviceInfo = rmfield(deviceInfo, 'Subsystems');
                metadata.DeviceInfo = deviceInfo;
                metadata.MeasurementType = app.MeasurementTypeDropDown.Value;
                metadata.Range = app.RangeDropDown.Value;
                metadata.Coupling = app.CouplingDropDown.Value;
                metadata.TerminalConfig = app.TerminalConfigDropDown.Value;
                metadata.ExcitationSource = app.ExcitationSourceDropDown.Value;
                metadata.Rate = app.RateEdit.Value;
                metadata.TriggerTime = datetime(app.TriggerTime, 'ConvertFrom', 'datenum', 'TimeZone', 'local');
                
                % Open "Save As" to request destination MAT file path and file name from user
                [filename, pathname] = uiputfile({'*.mat'}, 'Save as',...
                    fullfile(app.Filepath, app.Filename));
                
                if ~(isequal(filename,0) || isequal(pathname,0))
                    % User specified a file name in a folder with write permission
                    app.Filename = filename;
                    app.Filepath = pathname;
                    cancelSaveAs = false;
                else
                    %  User clicked Cancel in "Save As" dialog
                    cancelSaveAs = true;
                end
                
                if ~cancelSaveAs
                    % Convert data from binary file to MAT file
                    matFilepath = fullfile(app.Filepath, app.Filename);
                    app.LogStatusText.Text = 'Saving data to MAT file is in progress...';
                    drawnow
                    
                    numColumns = 4;
                    binFile2MAT(app, app.TempFilename, matFilepath, numColumns, metadata);
                    app.LogStatusText.Text = sprintf('Saving data to ''%s'' file has completed.', app.Filename);
                    
                else
                    % User clicked Cancel in "Save As" dialog
                    % Inform user that data has not been saved
                    app.LogStatusText.Text = 'Saving data to MAT file was cancelled.';
                end
            end
            
            % Enable DAQ device, channel properties, and start acquisition UI components
            setAppViewState(app, 'configuration');
            setAppViewState_2(app, 'configuration');
        end

        % Callback function
        function ChannelDropDownValueChanged(app, event)
            
            updateChannelMeasurementComponents(app)
            
        end

        % Value changed function: CouplingDropDown, 
        % ExcitationSourceDropDown, RangeDropDown, 
        % TerminalConfigDropDown
        function ChannelPropertyValueChanged(app, event)
            % Shared callback for RangeDropDown, TerminalConfigDropDown, CouplingDropDown, and ExcitationSourceDropDown
            
            % This executes only for 'Voltage' measurement type, since for 'Audio' measurement
            % type Range never changes, and TerminalConfig and Coupling are disabled.
            
            value = event.Source.Value;
            
            % Set channel property to selected value
            % The channel property name was previously stored in the UI component Tag
            propertyName = event.Source.Tag;
            try
                set(app.DAQ.Channels(1), propertyName, value);
            catch exception
                % In case of error show it and revert the change
                uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Channel property error');
                event.Source.Value = event.PreviousValue;
            end
            
            % Make sure shown channel property values are not stale, as some property update can trigger changes in other properties
            % Update UI with current channel property values from data acquisition object
            currentRange = app.DAQ.Channels(1).Range;
            app.RangeDropDown.Value = [currentRange.Min currentRange.Max];
            app.TerminalConfigDropDown.Value = app.DAQ.Channels(1).TerminalConfig;
            app.CouplingDropDown.Value = app.DAQ.Channels(1).Coupling;
            
        end

        % Value changing function: RateSlider
        function RateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RateEdit.Value = changingValue;
        end

        % Value changed function: RateEdit, RateSlider
        function RateSliderValueChanged(app, event)
            % Shared callback for RateSlider and RateEdit
            
            value = event.Source.Value;
            if ~isempty(app.DAQ)
                app.DAQ.Rate = value;
                
                % Update UI with current rate and time window limits
                updateRateUIComponents(app)
                
            end
        end

        % Value changed function: YmaxEditField, YminEditField
        function YmaxminValueChanged(app, event)
            % Shared callback for YmaxEditField and YminEditField
            
            ymin = app.YminEditField.Value;
            ymax = app.YmaxEditField.Value;
            if ymax>ymin
                ylim(app.LiveAxes, [ymin, ymax]);
                ylim(app.LiveAxes_2, [ymin, ymax]);
                ylim(app.LiveAxes_3, [ymin, ymax]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: AutoscaleYSwitch
        function AutoscaleYSwitchValueChanged(app, event)
            updateAutoscaleYSwitchComponents(app)
        end

        % Value changed function: LogdatatofileSwitch
        function LogdatatofileSwitchValueChanged(app, event)
            updateLogdatatofileSwitchComponents(app)
        end

        % Close request function: LiveDataAcquisitionUIFigure
        function LiveDataAcquisitionCloseRequest(app, event)
            
            isAcquiring = ~isempty(app.DAQ) && app.DAQ.Running;
            if isAcquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.LiveDataAcquisitionUIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
            
        end

        % Value changed function: MeasurementTypeDropDown
        function MeasurementTypeDropDownValueChanged(app, event)
            
            updateChannelMeasurementComponents(app)

        end

        % Value changed function: XmaxEditField, XminEditField
        function XmaxminEditFieldValueChanged(app, event)
            xmin = app.XminEditField.Value;
            xmax = app.XmaxEditField.Value;
            if xmax>xmin
                xlim(app.LiveAxes_4, [xmin, xmax]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: YmaxEditField_2, YminEditField_2
        function YmaxminEditField_2ValueChanged(app, event)
            % Shared callback for YmaxEditField and YminEditField
            
            ymin_2 = app.YminEditField.Value;
            ymax_2 = app.YmaxEditField.Value;
            if ymax_2>ymin_2
                ylim(app.LiveAxes_4, [ymin_2, ymax_2]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            value = app.XAxisDropDown.Value;
            app.CI1_No = 0;
            app.CI2_No = 0;
            app.CI1_We = 0;
            app.CI2_We = 0;
            xlabel(app.LiveAxes_4, char(value));
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            value = app.YAxisDropDown.Value;
            app.CI1_No = 0;
            app.CI2_No = 0;
            app.CI1_We = 0;
            app.CI2_We = 0;
            ylabel(app.LiveAxes_4, char(value));
        end

        % Value changed function: AutoscaleSwitch
        function AutoscaleSwitchValueChanged(app, event)
            updateAutoscaleYSwitchComponents_2(app)
        end

        % Value changed function: XmaxEditField_2, XminEditField_2
        function XmaxminEditField_2ValueChanged(app, event)
            xmin_2 = app.XminEditField_2.Value;
            xmax_2 = app.XmaxEditField_2.Value;
            if xmax_2>xmin_2
                xlim(app.LiveAxes_5, [xmin_2, xmax_2]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: YmaxEditField_3, YminEditField_3
        function YmaxminEditField_3ValueChanged(app, event)
            ymin_3 = app.YminEditField_3.Value;
            ymax_3 = app.YmaxEditField_3.Value;
            if ymax_3>ymin_3
                ylim(app.LiveAxes_5, [ymin_3, ymax_3]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: AutoscaleSwitch_2
        function AutoscaleSwitch_2ValueChanged(app, event)
            updateAutoscaleYSwitchComponents_3(app)
        end

        % Button pushed function: SelectChangeModelButton
        function SelectChangeModelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat');
            app.mod = load(append(pathname, filename));
        end

        % Value changed function: SesitivityofXAxisEditField
        function SesitivityofXAxisEditFieldValueChanged(app, event)
            value = app.SesitivityofXAxisEditField.Value;
            app.DAQ.Channels(1).Sensitivity = value;
        end

        % Value changed function: SesitivityofYAxisEditField
        function SesitivityofYAxisEditFieldValueChanged(app, event)
            value = app.SesitivityofYAxisEditField.Value;
            app.DAQ.Channels(2).Sensitivity = value;
        end

        % Value changed function: SesitivityofZAxisEditField
        function SesitivityofZAxisEditFieldValueChanged(app, event)
            value = app.SesitivityofZAxisEditField.Value;
            app.DAQ.Channels(3).Sensitivity = value;
        end

        % Value changed function: AutoscaleSwitch_3
        function AutoscaleSwitch_3ValueChanged(app, event)
            updateAutoscaleYSwitchComponents_4(app)
        end

        % Value changed function: SesitivityofXAxisEditField_2
        function SesitivityofXAxisEditField_2ValueChanged(app, event)
            value = app.SesitivityofXAxisEditField_2.Value;
            app.DAQ_2.Channels(1).Sensitivity = value;
        end

        % Value changed function: SesitivityofYAxisEditField_2
        function SesitivityofYAxisEditField_2ValueChanged(app, event)
            value = app.SesitivityofYAxisEditField_2.Value;
            app.DAQ_2.Channels(2).Sensitivity = value;
        end

        % Value changed function: SesitivityofZAxisEditField_2
        function SesitivityofZAxisEditField_2ValueChanged(app, event)
            value = app.SesitivityofZAxisEditField_2.Value;
            app.DAQ_2.Channels(3).Sensitivity = value;
        end

        % Value changed function: RateSlider_2
        function RateSlider_2ValueChanged(app, event)

            value = event.Source.Value;
            if ~isempty(app.DAQ_2)
                app.DAQ_2.Rate = value;
                
                % Update UI with current rate and time window limits
                updateRateUIComponents_2(app)
                
            end
        end

        % Value changed function: DeviceDropDown_2
        function DeviceDropDown_2ValueChanged(app, event)
            value = app.DeviceDropDown_2.Value;
            
            if ~isempty(value)
                % Device index is offset by 1 because first element in device dropdown
                % is "Select a device" (not a device).
                deviceIndex = value-1 ;
                
                % Reset channel property options
                app.MeasurementTypeDropDown_2.Items = {''};
                app.RangeDropDown_2.Items = {''};
                app.TerminalConfigDropDown_2.Items = {''};
                app.CouplingDropDown_2.Items = {''};
                app.ExcitationSourceDropDown_2.Items = {''};
                setAppViewState_2(app, 'deviceselection');

                
                % Delete data acquisition object, as a new one will be created for the newly selected device
                delete(app.DAQ_2);
                app.DAQ_2 = [];
                
                if deviceIndex > 0
                    % If a device is selected
         
                    % Get subsystem information to update channel dropdown list and channel property options
                    % For devices that have an analog input or an audio input subsystem, this is the first subsystem
                    subsystem = app.DevicesInfo_2(deviceIndex).Subsystems(1);
                                        
                    % Populate available measurement types for the selected device
                    app.MeasurementTypeDropDown_2.Items = intersect(app.DAQMeasurementTypes_2,...
                                subsystem.MeasurementTypesAvailable, 'stable');

                    % Update channel and channel property options
                    updateChannelMeasurementComponents_2(app)

                else
                    % If no device is selected

                    % Delete existing data acquisition object
                    delete(app.DAQ_2);
                    app.DAQ_2 = [];
                    
                    app.DeviceDropDown_2.Items{1} = 'Select a device';
                    
                    setAppViewState_2(app, 'deviceselection');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LiveDataAcquisitionUIFigure = uifigure('Visible', 'off');
            app.LiveDataAcquisitionUIFigure.Name = 'Live Data Acquisition';
            app.LiveDataAcquisitionUIFigure.CloseRequestFcn = createCallbackFcn(app, @LiveDataAcquisitionCloseRequest, true);
            app.TabGroup = uitabgroup(app.LiveDataAcquisitionUIFigure);
            app.Spindle1 = uitab(app.TabGroup);
            app.Spindle1.Title = 'Spindle1';
            app.DevicePanel = uipanel(app.Spindle1);
            app.Image = uiimage(app.DevicePanel);
            app.Image.ImageSource = 'X.png';
            app.DirectionalNatureLabel = uilabel(app.DevicePanel);
            app.DirectionalNatureLabel.Text = 'Directional Nature';
            app.DeviceDropDownLabel = uilabel(app.DevicePanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DevicePanel);
            app.DeviceDropDown.Items = {'Detecting devices...'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Detecting devices...';
            app.MeasurementTypeDropDownLabel = uilabel(app.DevicePanel);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.MeasurementTypeDropDown = uidropdown(app.DevicePanel);
            app.MeasurementTypeDropDown.Items = {};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown.Value = {};
            app.RangeDropDownLabel = uilabel(app.DevicePanel);
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.DevicePanel);
            app.RangeDropDown.Items = {};
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.RangeDropDown.Value = {};
            app.TerminalConfigDropDownLabel = uilabel(app.DevicePanel);
            app.TerminalConfigDropDownLabel.Text = 'Terminal Config.';
            app.TerminalConfigDropDown = uidropdown(app.DevicePanel);
            app.TerminalConfigDropDown.Items = {};
            app.TerminalConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.TerminalConfigDropDown.Value = {};
            app.CouplingDropDownLabel = uilabel(app.DevicePanel);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingDropDown = uidropdown(app.DevicePanel);
            app.CouplingDropDown.Items = {};
            app.CouplingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.CouplingDropDown.Value = {};
            app.ExcitationSourceDropDownLabel = uilabel(app.DevicePanel);
            app.ExcitationSourceDropDownLabel.Text = 'Excitation Source';
            app.ExcitationSourceDropDown = uidropdown(app.DevicePanel);
            app.ExcitationSourceDropDown.Items = {};
            app.ExcitationSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.ExcitationSourceDropDown.Value = {};
            app.RateEdit = uieditfield(app.DevicePanel, 'numeric');
            app.RateEdit.Limits = [1e-06 10000000];
            app.RateEdit.ValueDisplayFormat = '%.1f';
            app.RateEdit.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateEdit.Value = 1000;
            app.RatescanssSliderLabel = uilabel(app.DevicePanel);
            app.RatescanssSliderLabel.Text = 'Rate (scans/s)';
            app.RateSlider = uislider(app.DevicePanel);
            app.RateSlider.Limits = [1e-06 1000];
            app.RateSlider.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateSlider.ValueChangingFcn = createCallbackFcn(app, @RateSliderValueChanging, true);
            app.RateSlider.Value = 1000;
            app.SesitivityofXAxisEditFieldLabel = uilabel(app.DevicePanel);
            app.SesitivityofXAxisEditFieldLabel.Text = 'Sesitivity of X-Axis';
            app.SesitivityofXAxisEditField = uieditfield(app.DevicePanel, 'numeric');
            app.SesitivityofXAxisEditField.ValueChangedFcn = createCallbackFcn(app, @SesitivityofXAxisEditFieldValueChanged, true);
            app.SesitivityofXAxisEditField.Value = 5.15;
            app.SesitivityofYAxisEditFieldLabel = uilabel(app.DevicePanel);
            app.SesitivityofYAxisEditFieldLabel.Text = 'Sesitivity of Y-Axis';
            app.SesitivityofYAxisEditField = uieditfield(app.DevicePanel, 'numeric');
            app.SesitivityofYAxisEditField.ValueChangedFcn = createCallbackFcn(app, @SesitivityofYAxisEditFieldValueChanged, true);
            app.SesitivityofYAxisEditField.Value = 5.09;
            app.SesitivityofZAxisEditFieldLabel = uilabel(app.DevicePanel);
            app.SesitivityofZAxisEditFieldLabel.Text = 'Sesitivity of Z-Axis';
            app.SesitivityofZAxisEditField = uieditfield(app.DevicePanel, 'numeric');
            app.SesitivityofZAxisEditField.ValueChangedFcn = createCallbackFcn(app, @SesitivityofZAxisEditFieldValueChanged, true);
            app.SesitivityofZAxisEditField.Value = 5.08;
            app.Panel = uipanel(app.Spindle1);
            app.Lamp = uilamp(app.Panel);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.SelectChangeModelButton = uibutton(app.Panel, 'push');
            app.SelectChangeModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectChangeModelButtonPushed, true);
            app.SelectChangeModelButton.Text = 'Select/Change Model';
            app.AccuracyRateLabel = uilabel(app.Panel);
            app.AccuracyRateLabel.Text = 'Accuracy Rate (%)';
            app.AccuracyRateEditField = uieditfield(app.Panel, 'numeric');
            app.LiveViewPanel = uipanel(app.Spindle1);
            app.LiveAxes = uiaxes(app.LiveViewPanel);
            title(app.LiveAxes, 'Acceleration of Axis X')
            xlabel(app.LiveAxes, 'Time (s)')
            ylabel(app.LiveAxes, 'Voltage (V)')
            app.LiveAxes_2 = uiaxes(app.LiveViewPanel);
            title(app.LiveAxes_2, 'Acceleration of Axis Y')
            xlabel(app.LiveAxes_2, 'Time (s)')
            ylabel(app.LiveAxes_2, 'Voltage (V)')
            app.LiveAxes_3 = uiaxes(app.LiveViewPanel);
            title(app.LiveAxes_3, 'Acceleration of Axis Z')
            xlabel(app.LiveAxes_3, 'Time (s)')
            ylabel(app.LiveAxes_3, 'Voltage (V)')
            app.AutoscaleYSwitchLabel = uilabel(app.LiveViewPanel);
            app.AutoscaleYSwitchLabel.Text = 'Autoscale Y';
            app.AutoscaleYSwitch = uiswitch(app.LiveViewPanel, 'slider');
            app.AutoscaleYSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleYSwitchValueChanged, true);
            app.AutoscaleYSwitch.Value = 'On';
            app.YminEditFieldLabel = uilabel(app.LiveViewPanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxminValueChanged, true);
            app.YminEditField.Value = -1;
            app.YmaxEditFieldLabel = uilabel(app.LiveViewPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxminValueChanged, true);
            app.YmaxEditField.Value = 1;
            app.TimewindowsEditFieldLabel = uilabel(app.LiveViewPanel);
            app.TimewindowsEditFieldLabel.Text = 'Time window (s)';
            app.TimewindowEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.TimewindowEditField.Value = 5;
            app.DNFNumberofXAxisEditFieldLabel = uilabel(app.LiveViewPanel);
            app.DNFNumberofXAxisEditFieldLabel.Text = 'DNF Number of X Axis';
            app.DNFNumberofXAxisEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.DNFNumberofYAxisEditFieldLabel = uilabel(app.LiveViewPanel);
            app.DNFNumberofYAxisEditFieldLabel.Text = 'DNF Number of Y Axis';
            app.DNFNumberofYAxisEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.DNFNumberofZAxisEditFieldLabel = uilabel(app.LiveViewPanel);
            app.DNFNumberofZAxisEditFieldLabel.Text = 'DNF Number of Z Axis';
            app.DNFNumberofZAxisEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.LiveViewPanel_2 = uipanel(app.Spindle1);
            app.LiveAxes_4 = uiaxes(app.LiveViewPanel_2);
            title(app.LiveAxes_4, 'Scatter of Health Indicator')
            xlabel(app.LiveAxes_4, 'Mean')
            ylabel(app.LiveAxes_4, 'SD')
            app.AutoscaleSwitchLabel = uilabel(app.LiveViewPanel_2);
            app.AutoscaleSwitchLabel.Text = 'Autoscale';
            app.AutoscaleSwitch = uiswitch(app.LiveViewPanel_2, 'slider');
            app.AutoscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleSwitchValueChanged, true);
            app.AutoscaleSwitch.Value = 'On';
            app.YminEditField_2Label = uilabel(app.LiveViewPanel_2);
            app.YminEditField_2Label.Text = 'Ymin';
            app.YminEditField_2 = uieditfield(app.LiveViewPanel_2, 'numeric');
            app.YminEditField_2.ValueChangedFcn = createCallbackFcn(app, @YmaxminEditField_2ValueChanged, true);
            app.YminEditField_2.Value = -1;
            app.YmaxEditField_2Label = uilabel(app.LiveViewPanel_2);
            app.YmaxEditField_2Label.Text = 'Ymax';
            app.YmaxEditField_2 = uieditfield(app.LiveViewPanel_2, 'numeric');
            app.YmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @YmaxminEditField_2ValueChanged, true);
            app.YmaxEditField_2.Value = 1;
            app.XminEditFieldLabel = uilabel(app.LiveViewPanel_2);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.LiveViewPanel_2, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxminEditFieldValueChanged, true);
            app.XminEditField.Value = -1;
            app.XmaxEditFieldLabel = uilabel(app.LiveViewPanel_2);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.LiveViewPanel_2, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxminEditFieldValueChanged, true);
            app.XmaxEditField.Value = 1;
            app.XAxisLabel = uilabel(app.LiveViewPanel_2);
            app.XAxisLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.LiveViewPanel_2);
            app.XAxisDropDown.Items = {'Mean', 'SD', 'RMS', 'Skewness', 'Kurtosis'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'Mean';
            app.YAxisDropDownLabel = uilabel(app.LiveViewPanel_2);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.LiveViewPanel_2);
            app.YAxisDropDown.Items = {'Mean', 'SD', 'RMS', 'Skewness', 'Kurtosis'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'SD';
            app.NumberofDataEditFieldLabel = uilabel(app.LiveViewPanel_2);
            app.NumberofDataEditFieldLabel.Text = 'Number of Data';
            app.NumberofDataEditField = uieditfield(app.LiveViewPanel_2, 'numeric');
            app.NumberofDataEditField.Limits = [1 10000];
            app.NumberofDataEditField.Value = 5000;
            app.Panel_3 = uipanel(app.Spindle1);
            app.LiveAxes_6 = uiaxes(app.Panel_3);
            title(app.LiveAxes_6, 'Plot of Remaning Useful Iife')
            xlabel(app.LiveAxes_6, 'Time')
            ylabel(app.LiveAxes_6, 'Estimation RUL')
            zlabel(app.LiveAxes_6, 'Z')
            app.EstimationRemainingUsefulLifeEditFieldLabel = uilabel(app.Panel_3);
            app.EstimationRemainingUsefulLifeEditFieldLabel.Text = 'Estimation Remaining Useful Life';
            app.EstimationRemainingUsefulLifeEditField = uieditfield(app.Panel_3, 'numeric');
            app.AutoscaleSwitch_3Label = uilabel(app.Panel_3);
            app.AutoscaleSwitch_3Label.Text = 'Autoscale';
            app.AutoscaleSwitch_3 = uiswitch(app.Panel_3, 'slider');
            app.AutoscaleSwitch_3.ValueChangedFcn = createCallbackFcn(app, @AutoscaleSwitch_3ValueChanged, true);
            app.Panel_2 = uipanel(app.Spindle1);
            app.LiveAxes_5 = uiaxes(app.Panel_2);
            title(app.LiveAxes_5, 'Fast Fourier Transform')
            xlabel(app.LiveAxes_5, 'Frequency')
            ylabel(app.LiveAxes_5, 'Amplitude')
            app.AutoscaleSwitch_2Label = uilabel(app.Panel_2);
            app.AutoscaleSwitch_2Label.Text = 'Autoscale';
            app.AutoscaleSwitch_2 = uiswitch(app.Panel_2, 'slider');
            app.AutoscaleSwitch_2.ValueChangedFcn = createCallbackFcn(app, @AutoscaleSwitch_2ValueChanged, true);
            app.YminEditField_3Label = uilabel(app.Panel_2);
            app.YminEditField_3Label.Text = 'Ymin';
            app.YminEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.YminEditField_3.ValueChangedFcn = createCallbackFcn(app, @YmaxminEditField_3ValueChanged, true);
            app.YmaxEditField_3Label = uilabel(app.Panel_2);
            app.YmaxEditField_3Label.Text = 'Ymax';
            app.YmaxEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.YmaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @YmaxminEditField_3ValueChanged, true);
            app.YmaxEditField_3.Value = 0.0002;
            app.XminEditField_2Label = uilabel(app.Panel_2);
            app.XminEditField_2Label.Text = 'Xmin';
            app.XminEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.XminEditField_2.ValueChangedFcn = createCallbackFcn(app, @XmaxminEditField_2ValueChanged, true);
            app.XmaxEditField_2Label = uilabel(app.Panel_2);
            app.XmaxEditField_2Label.Text = 'Xmax';
            app.XmaxEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.XmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @XmaxminEditField_2ValueChanged, true);
            app.XmaxEditField_2.Value = 300;
            app.Spindle2 = uitab(app.TabGroup);
            app.Spindle2.Title = 'Spindle2';
            app.LiveViewPanel_3 = uipanel(app.Spindle2);
            app.LiveAxes_4_2 = uiaxes(app.LiveViewPanel_3);
            title(app.LiveAxes_4_2, 'Scatter of Health Indicator')
            xlabel(app.LiveAxes_4_2, 'Mean')
            ylabel(app.LiveAxes_4_2, 'SD')
            app.AutoscaleSwitch_4Label = uilabel(app.LiveViewPanel_3);
            app.AutoscaleSwitch_4Label.Text = 'Autoscale';
            app.AutoscaleSwitch__2 = uiswitch(app.LiveViewPanel_3, 'slider');
            app.AutoscaleSwitch__2.Value = 'On';
            app.YminEditField_4Label = uilabel(app.LiveViewPanel_3);
            app.YminEditField_4Label.Text = 'Ymin';
            app.YminEditField_2_2 = uieditfield(app.LiveViewPanel_3, 'numeric');
            app.YminEditField_2_2.Value = -1;
            app.YmaxEditField_4Label = uilabel(app.LiveViewPanel_3);
            app.YmaxEditField_4Label.Text = 'Ymax';
            app.YmaxEditField_2_2 = uieditfield(app.LiveViewPanel_3, 'numeric');
            app.YmaxEditField_2_2.Value = 1;
            app.XminEditField_3Label = uilabel(app.LiveViewPanel_3);
            app.XminEditField_3Label.Text = 'Xmin';
            app.XminEditField__2 = uieditfield(app.LiveViewPanel_3, 'numeric');
            app.XminEditField__2.Value = -1;
            app.XmaxEditField_3Label = uilabel(app.LiveViewPanel_3);
            app.XmaxEditField_3Label.Text = 'Xmax';
            app.XmaxEditField__2 = uieditfield(app.LiveViewPanel_3, 'numeric');
            app.XmaxEditField__2.Value = 1;
            app.XAxisDropDown_2Label = uilabel(app.LiveViewPanel_3);
            app.XAxisDropDown_2Label.Text = 'X-Axis';
            app.XAxisDropDown_2 = uidropdown(app.LiveViewPanel_3);
            app.XAxisDropDown_2.Items = {'Mean', 'SD', 'RMS', 'Skewness', 'Kurtosis'};
            app.XAxisDropDown_2.Value = 'Mean';
            app.YAxisDropDown_2Label = uilabel(app.LiveViewPanel_3);
            app.YAxisDropDown_2Label.Text = 'Y-Axis';
            app.YAxisDropDown_2 = uidropdown(app.LiveViewPanel_3);
            app.YAxisDropDown_2.Items = {'Mean', 'SD', 'RMS', 'Skewness', 'Kurtosis'};
            app.YAxisDropDown_2.Value = 'SD';
            app.NumberofDataEditField_2Label = uilabel(app.LiveViewPanel_3);
            app.NumberofDataEditField_2Label.Text = 'Number of Data';
            app.NumberofDataEditField_2 = uieditfield(app.LiveViewPanel_3, 'numeric');
            app.NumberofDataEditField_2.Limits = [1 10000];
            app.NumberofDataEditField_2.Value = 5000;
            app.DevicePanel_2 = uipanel(app.Spindle2);
            app.Image_2 = uiimage(app.DevicePanel_2);
            app.Image_2.ImageSource = 'X.png';
            app.DirectionalNatureLabel_2 = uilabel(app.DevicePanel_2);
            app.DirectionalNatureLabel_2.Text = 'Directional Nature';
            app.DeviceDropDown_2Label = uilabel(app.DevicePanel_2);
            app.DeviceDropDown_2Label.Text = 'Device';
            app.DeviceDropDown_2 = uidropdown(app.DevicePanel_2);
            app.DeviceDropDown_2.Items = {'Detecting devices...'};
            app.DeviceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDown_2ValueChanged, true);
            app.DeviceDropDown_2.Value = 'Detecting devices...';
            app.MeasurementTypeDropDown_2Label = uilabel(app.DevicePanel_2);
            app.MeasurementTypeDropDown_2Label.Text = 'Measurement Type';
            app.MeasurementTypeDropDown_2 = uidropdown(app.DevicePanel_2);
            app.MeasurementTypeDropDown_2.Items = {};
            app.MeasurementTypeDropDown_2.Value = {};
            app.RangeDropDown_2Label = uilabel(app.DevicePanel_2);
            app.RangeDropDown_2Label.Text = 'Range';
            app.RangeDropDown_2 = uidropdown(app.DevicePanel_2);
            app.RangeDropDown_2.Items = {};
            app.RangeDropDown_2.Value = {};
            app.TerminalConfigDropDown_2Label = uilabel(app.DevicePanel_2);
            app.TerminalConfigDropDown_2Label.Text = 'Terminal Config.';
            app.TerminalConfigDropDown_2 = uidropdown(app.DevicePanel_2);
            app.TerminalConfigDropDown_2.Items = {};
            app.TerminalConfigDropDown_2.Value = {};
            app.CouplingDropDown_2Label = uilabel(app.DevicePanel_2);
            app.CouplingDropDown_2Label.Text = 'Coupling';
            app.CouplingDropDown_2 = uidropdown(app.DevicePanel_2);
            app.CouplingDropDown_2.Items = {};
            app.CouplingDropDown_2.Value = {};
            app.ExcitationSourceDropDown_2Label = uilabel(app.DevicePanel_2);
            app.ExcitationSourceDropDown_2Label.Text = 'Excitation Source';
            app.ExcitationSourceDropDown_2 = uidropdown(app.DevicePanel_2);
            app.ExcitationSourceDropDown_2.Items = {};
            app.ExcitationSourceDropDown_2.Value = {};
            app.RateEdit_2 = uieditfield(app.DevicePanel_2, 'numeric');
            app.RateEdit_2.Limits = [1e-06 10000000];
            app.RateEdit_2.ValueDisplayFormat = '%.1f';
            app.RateEdit_2.Value = 1000;
            app.RatescanssSliderLabel_2 = uilabel(app.DevicePanel_2);
            app.RatescanssSliderLabel_2.Text = 'Rate (scans/s)';
            app.RateSlider_2 = uislider(app.DevicePanel_2);
            app.RateSlider_2.Limits = [1e-06 1000];
            app.RateSlider_2.ValueChangedFcn = createCallbackFcn(app, @RateSlider_2ValueChanged, true);
            app.RateSlider_2.Value = 1000;
            app.SesitivityofXAxisEditField_2Label = uilabel(app.DevicePanel_2);
            app.SesitivityofXAxisEditField_2Label.Text = 'Sesitivity of X-Axis';
            app.SesitivityofXAxisEditField_2 = uieditfield(app.DevicePanel_2, 'numeric');
            app.SesitivityofXAxisEditField_2.ValueChangedFcn = createCallbackFcn(app, @SesitivityofXAxisEditField_2ValueChanged, true);
            app.SesitivityofXAxisEditField_2.Value = 5.15;
            app.SesitivityofYAxisEditField_2Label = uilabel(app.DevicePanel_2);
            app.SesitivityofYAxisEditField_2Label.Text = 'Sesitivity of Y-Axis';
            app.SesitivityofYAxisEditField_2 = uieditfield(app.DevicePanel_2, 'numeric');
            app.SesitivityofYAxisEditField_2.ValueChangedFcn = createCallbackFcn(app, @SesitivityofYAxisEditField_2ValueChanged, true);
            app.SesitivityofYAxisEditField_2.Value = 5.09;
            app.SesitivityofZAxisEditField_2Label = uilabel(app.DevicePanel_2);
            app.SesitivityofZAxisEditField_2Label.Text = 'Sesitivity of Z-Axis';
            app.SesitivityofZAxisEditField_2 = uieditfield(app.DevicePanel_2, 'numeric');
            app.SesitivityofZAxisEditField_2.ValueChangedFcn = createCallbackFcn(app, @SesitivityofZAxisEditField_2ValueChanged, true);
            app.SesitivityofZAxisEditField_2.Value = 5.08;
            app.LiveViewPanel_4 = uipanel(app.Spindle2);
            app.LiveAxes__2 = uiaxes(app.LiveViewPanel_4);
            title(app.LiveAxes__2, 'Acceleration of Axis X')
            xlabel(app.LiveAxes__2, 'Time (s)')
            ylabel(app.LiveAxes__2, 'Voltage (V)')
            app.LiveAxes_2_2 = uiaxes(app.LiveViewPanel_4);
            title(app.LiveAxes_2_2, 'Acceleration of Axis Y')
            xlabel(app.LiveAxes_2_2, 'Time (s)')
            ylabel(app.LiveAxes_2_2, 'Voltage (V)')
            app.LiveAxes_3_2 = uiaxes(app.LiveViewPanel_4);
            title(app.LiveAxes_3_2, 'Acceleration of Axis Z')
            xlabel(app.LiveAxes_3_2, 'Time (s)')
            ylabel(app.LiveAxes_3_2, 'Voltage (V)')
            app.AutoscaleYSwitch_2Label = uilabel(app.LiveViewPanel_4);
            app.AutoscaleYSwitch_2Label.Text = 'Autoscale Y';
            app.AutoscaleYSwitch_2 = uiswitch(app.LiveViewPanel_4, 'slider');
            app.AutoscaleYSwitch_2.Value = 'On';
            app.YminEditField_5Label = uilabel(app.LiveViewPanel_4);
            app.YminEditField_5Label.Text = 'Ymin';
            app.YminEditField__2 = uieditfield(app.LiveViewPanel_4, 'numeric');
            app.YminEditField__2.Value = -1;
            app.YmaxEditField_5Label = uilabel(app.LiveViewPanel_4);
            app.YmaxEditField_5Label.Text = 'Ymax';
            app.YmaxEditField__2 = uieditfield(app.LiveViewPanel_4, 'numeric');
            app.YmaxEditField__2.Value = 1;
            app.TimewindowsEditFieldLabel_2 = uilabel(app.LiveViewPanel_4);
            app.TimewindowsEditFieldLabel_2.Text = 'Time window (s)';
            app.TimewindowEditField_2 = uieditfield(app.LiveViewPanel_4, 'numeric');
            app.TimewindowEditField_2.Value = 5;
            app.DNFNumberofXAxisEditField_2Label = uilabel(app.LiveViewPanel_4);
            app.DNFNumberofXAxisEditField_2Label.Text = 'DNF Number of X Axis';
            app.DNFNumberofXAxisEditField_2 = uieditfield(app.LiveViewPanel_4, 'numeric');
            app.DNFNumberofYAxisEditField_2Label = uilabel(app.LiveViewPanel_4);
            app.DNFNumberofYAxisEditField_2Label.Text = 'DNF Number of Y Axis';
            app.DNFNumberofYAxisEditField_2 = uieditfield(app.LiveViewPanel_4, 'numeric');
            app.DNFNumberofZAxisEditField_2Label = uilabel(app.LiveViewPanel_4);
            app.DNFNumberofZAxisEditField_2Label.Text = 'DNF Number of Z Axis';
            app.DNFNumberofZAxisEditField_2 = uieditfield(app.LiveViewPanel_4, 'numeric');
            app.Panel_4 = uipanel(app.Spindle2);
            app.Lamp_2 = uilamp(app.Panel_4);
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '';
            app.SelectChangeModelButton_2 = uibutton(app.Panel_4, 'push');
            app.SelectChangeModelButton_2.Text = 'Select/Change Model';
            app.AccuracyRateEditField_2Label = uilabel(app.Panel_4);
            app.AccuracyRateEditField_2Label.Text = 'Accuracy Rate (%)';
            app.AccuracyRateEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.Panel_5 = uipanel(app.Spindle2);
            app.LiveAxes_5_2 = uiaxes(app.Panel_5);
            title(app.LiveAxes_5_2, 'Fast Fourier Transform')
            xlabel(app.LiveAxes_5_2, 'Frequency')
            ylabel(app.LiveAxes_5_2, 'Amplitude')
            app.AutoscaleSwitch_5Label = uilabel(app.Panel_5);
            app.AutoscaleSwitch_5Label.Text = 'Autoscale';
            app.AutoscaleSwitch_2_2 = uiswitch(app.Panel_5, 'slider');
            app.YminEditField_6Label = uilabel(app.Panel_5);
            app.YminEditField_6Label.Text = 'Ymin';
            app.YminEditField_3_2 = uieditfield(app.Panel_5, 'numeric');
            app.YmaxEditField_6Label = uilabel(app.Panel_5);
            app.YmaxEditField_6Label.Text = 'Ymax';
            app.YmaxEditField_3_2 = uieditfield(app.Panel_5, 'numeric');
            app.YmaxEditField_3_2.Value = 0.0002;
            app.XminEditField_4Label = uilabel(app.Panel_5);
            app.XminEditField_4Label.Text = 'Xmin';
            app.XminEditField_2_2 = uieditfield(app.Panel_5, 'numeric');
            app.XmaxEditField_4Label = uilabel(app.Panel_5);
            app.XmaxEditField_4Label.Text = 'Xmax';
            app.XmaxEditField_2_2 = uieditfield(app.Panel_5, 'numeric');
            app.XmaxEditField_2_2.Value = 300;
            app.Panel_6 = uipanel(app.Spindle2);
            app.LiveAxes_6_2 = uiaxes(app.Panel_6);
            title(app.LiveAxes_6_2, 'Plot of Remaning Useful Iife')
            xlabel(app.LiveAxes_6_2, 'Time')
            ylabel(app.LiveAxes_6_2, 'Estimation RUL')
            zlabel(app.LiveAxes_6_2, 'Z')
            app.EstimationRemainingUsefulLifeEditField_2Label = uilabel(app.Panel_6);
            app.EstimationRemainingUsefulLifeEditField_2Label.Text = 'Estimation Remaining Useful Life';
            app.EstimationRemainingUsefulLifeEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.AutoscaleSwitch_6Label = uilabel(app.Panel_6);
            app.AutoscaleSwitch_6Label.Text = 'Autoscale';
            app.AutoscaleSwitch_3_2 = uiswitch(app.Panel_6, 'slider');
            app.AcquisitionPanel = uipanel(app.LiveDataAcquisitionUIFigure);
            app.StartButton = uibutton(app.AcquisitionPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AcquisitionPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LogdatatofileSwitchLabel = uilabel(app.AcquisitionPanel);
            app.LogdatatofileSwitchLabel.Text = 'Log data to file';
            app.LogdatatofileSwitch = uiswitch(app.AcquisitionPanel, 'slider');
            app.LogdatatofileSwitch.ValueChangedFcn = createCallbackFcn(app, @LogdatatofileSwitchValueChanged, true);
            app.LogStatusText = uilabel(app.AcquisitionPanel);
            app.LogStatusText.Text = '';
            app.SendtoServerSwitchLabel = uilabel(app.AcquisitionPanel);
            app.SendtoServerSwitchLabel.Text = 'Send to Server';
            app.SendtoServerSwitch = uiswitch(app.AcquisitionPanel, 'slider');
            app.Image2 = uiimage(app.LiveDataAcquisitionUIFigure);
            app.Image2.ImageSource = 'KU.jpg';
            app.Image3 = uiimage(app.LiveDataAcquisitionUIFigure);
            app.Image3.ImageSource = 'SiM.png';
            % Show the figure after all components are created
            app.LiveDataAcquisitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_GUI_for_PHM_Final_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LiveDataAcquisitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LiveDataAcquisitionUIFigure)
        end
    end
end