classdef PHM_GUI_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Lamp         matlab.ui.control.Lamp
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        state = 'test';
        mod = load('D:/DAQ-App-for-PHM/TestModel.mat');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = 'yellow';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.state = string(app.mod.trainedModel.predictFcn(table(value, 'VariableNames', {'Var1'})));
            if app.state == 'normal'
                app.Lamp.Color = 'blue';
            elseif app.state == 'fault'
                app.Lamp.Color = 'red';
            else
                app.Lamp.Color = 'green';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp = uilamp(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.5 1];
            app.Slider.MajorTickLabels = {'0', '0.5', '1', '', ''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PHM_GUI_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end